classdef DAST < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        UploadedPresetEditField         matlab.ui.control.EditField
        LoadPresetButton                matlab.ui.control.Button
        AudioSettingsTabs               matlab.ui.container.TabGroup
        QuickstartTab                   matlab.ui.container.Tab
        ListTypeDropDown                matlab.ui.control.DropDown
        ListtypeDropDownLabel           matlab.ui.control.Label
        dBLabel                         matlab.ui.control.Label
        SNRValue                        matlab.ui.control.Label
        SNRLabel                        matlab.ui.control.Label
        MaskerLevelEditField            matlab.ui.control.NumericEditField
        MaskerlevelEditFieldLabel       matlab.ui.control.Label
        TargetLevelEditField            matlab.ui.control.NumericEditField
        TargetlevelLabel                matlab.ui.control.Label
        MaskerDropDown                  matlab.ui.control.DropDown
        MaskerLabel                     matlab.ui.control.Label
        ListNumberDropDown              matlab.ui.control.DropDown
        ListnumberDropDownLabel         matlab.ui.control.Label
        TargetDropDown                  matlab.ui.control.DropDown
        TargetDropDownLabel             matlab.ui.control.Label
        dBSPLLabel_2                    matlab.ui.control.Label
        dBSPLLabel                      matlab.ui.control.Label
        AdvancedTab                     matlab.ui.container.Tab
        StartingBlockDropDown           matlab.ui.control.DropDown
        StartingblockDropDownLabel      matlab.ui.control.Label
        UploadedCSVCheckBox             matlab.ui.control.CheckBox
        CSVFileEditField                matlab.ui.control.EditField
        CSVfileEditFieldLabel           matlab.ui.control.Label
        UploadButton                    matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        TestTab                         matlab.ui.container.Tab
        Instructions                    matlab.ui.control.Label
        ProgressPanel                   matlab.ui.container.Panel
        currentBlock                    matlab.ui.control.Label
        SentIdxVsSNR                    matlab.ui.control.UIAxes
        SNRVsPercent                    matlab.ui.control.UIAxes
        NextButton                      matlab.ui.control.Button
        PauseButton                     matlab.ui.control.StateButton
        DanskStningstestDASTLabel       matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ScoringPanel                    matlab.ui.container.Panel
        currentSent                     matlab.ui.control.Label
        W12Button                       matlab.ui.control.StateButton
        W11Button                       matlab.ui.control.StateButton
        W10Button                       matlab.ui.control.StateButton
        W9Button                        matlab.ui.control.StateButton
        W8Button                        matlab.ui.control.StateButton
        W7Button                        matlab.ui.control.StateButton
        W6Button                        matlab.ui.control.StateButton
        W5Button                        matlab.ui.control.StateButton
        W4Button                        matlab.ui.control.StateButton
        W3Button                        matlab.ui.control.StateButton
        W2Button                        matlab.ui.control.StateButton
        W1Button                        matlab.ui.control.StateButton
        MarkallButton                   matlab.ui.control.StateButton
        ContinueButton                  matlab.ui.control.Button
        SettingsTab                     matlab.ui.container.Tab
        SettingsPanelinSettingsTab      matlab.ui.container.Panel
        StoppingCriteriaEditField       matlab.ui.control.NumericEditField
        StoppingCriteriaLabel           matlab.ui.control.Label
        StoppingCriteriaDecibelLabel    matlab.ui.control.Label
        MaskerButtonGroup               matlab.ui.container.ButtonGroup
        DurationbeforeEditField         matlab.ui.control.NumericEditField
        DurationbeforeEditFieldLabel    matlab.ui.control.Label
        SecLabel                        matlab.ui.control.Label
        IntermittentButton              matlab.ui.control.RadioButton
        ContinuousButton                matlab.ui.control.RadioButton
        MiscellaneousButtonGroup        matlab.ui.container.ButtonGroup
        AudiogramFileEditField          matlab.ui.control.EditField
        AudiogramFileSelectButton       matlab.ui.control.Button
        AudiogramFileLabel              matlab.ui.control.Label
        AdjustSentenceLevelRMSCheckbox  matlab.ui.control.CheckBox
        RandomizeOrderWithinListsCheckbox  matlab.ui.control.CheckBox
        ApplyHearingLossCompensationCheckbox  matlab.ui.control.CheckBox
        ApplySettingsButton             matlab.ui.control.Button
        SavePresetButton                matlab.ui.control.Button
        CalibrationButtonGroup          matlab.ui.container.ButtonGroup
        EqualizationfilterEditFieldLabel_2  matlab.ui.control.Label
        LeftMeasuredEditFieldLabel      matlab.ui.control.Label
        LeftMeasuredEditField           matlab.ui.control.NumericEditField
        RightMeasuredEditField          matlab.ui.control.NumericEditField
        RightMeasuredEditFieldLabel     matlab.ui.control.Label
        SelectEqualizationFilterButton  matlab.ui.control.Button
        EqualizationFilterEditField     matlab.ui.control.EditField
        EqualizationfilterEditFieldLabel  matlab.ui.control.Label
        PlayCalibrationFileButton       matlab.ui.control.StateButton
        MeasuredLabel                   matlab.ui.control.Label
        dBZLabel                        matlab.ui.control.Label
        PlaybackmodeButtonGroup         matlab.ui.container.ButtonGroup
        MultiButton                     matlab.ui.control.RadioButton
        MonoButton                      matlab.ui.control.RadioButton
        StereoButton                    matlab.ui.control.RadioButton
        PlaybackMethodButtonGroup       matlab.ui.container.ButtonGroup
        ChannelsInEditField             matlab.ui.control.EditField
        ChannelsInEditFieldLabel        matlab.ui.control.Label
        BasicButton                     matlab.ui.control.RadioButton
        AudioToolboxButton              matlab.ui.control.RadioButton
        PlayrecButton                   matlab.ui.control.RadioButton
        DriverDropDownLabel             matlab.ui.control.Label
        DriverDropDown                  matlab.ui.control.DropDown
        ChannelsOutEditField            matlab.ui.control.EditField
        ChannelsOutEditFieldLabel       matlab.ui.control.Label
        ScoringTypeButtonGroup          matlab.ui.container.ButtonGroup
        WordByWordButton                matlab.ui.control.RadioButton
        KeywordBasedButton              matlab.ui.control.RadioButton
        PresentationModeButtonGroup     matlab.ui.container.ButtonGroup
        CurrentAudioDir                 matlab.ui.control.EditField
        PathtofilesLabel                matlab.ui.control.Label
        SelectVideoDirButton            matlab.ui.control.Button
        CurrentVideoDir                 matlab.ui.control.EditField
        PathtofilesLabel_2              matlab.ui.control.Label
        SelectAudioDirButton            matlab.ui.control.Button
        AudioVisualPresentationButton   matlab.ui.control.RadioButton
        AudioPresentationButton         matlab.ui.control.RadioButton
        ResultTypeButtonGroup           matlab.ui.container.ButtonGroup
        AdaptiveMethodDropdownLabel     matlab.ui.control.Label
        AdaptiveMethodDropdown          matlab.ui.control.DropDown
        SpeechReceptionThresholdButton  matlab.ui.control.RadioButton
        PercentageCorrectButton         matlab.ui.control.RadioButton
        BasicInformationPanel           matlab.ui.container.Panel
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        ParticipantIDEditField          matlab.ui.control.EditField
        ParticipantIDEditFieldLabel     matlab.ui.control.Label
        TesterIDEditField               matlab.ui.control.EditField
        TesterIDEditFieldLabel          matlab.ui.control.Label
    end


    properties (Access = public)

        % % Definition of global variables

        % Data that gets saved in results file
        data

        % Data that does not get saved in results file
        tmp

    end

    % Functions that require data outside of the app
    methods (Access = public)

    
    end

    % Functions that require data inside of the app
    methods (Access = private)

        function updateInfoForNextSent(app)

            % Update counters
            app.tmp.sentIdxInBlock = app.tmp.sentIdxInBlock + 1; % current sentence
            app.tmp.sentIdxInSession = app.tmp.sentIdxInSession + 1; % for saving results

            % Read in info for the next sentence
            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab

                app.tmp.blockNum = app.tmp.blockIdxInSession;
                app.tmp.target = app.TargetDropDown.Value;
                app.tmp.masker = app.MaskerDropDown.Value;
                app.tmp.listType = app.ListTypeDropDown.Value;
                app.tmp.listNum = str2double(app.ListNumberDropDown.Value);
                idxSentencesInList = find(app.tmp.(app.tmp.listType).List == app.tmp.listNum);
                app.tmp.sentTotInBlock = numel(idxSentencesInList);
                app.tmp.sentNum = app.tmp.(app.tmp.listType).Sentence(idxSentencesInList(app.tmp.sentIdxInBlock));

                % Update levels
                if app.data.Settings.ResultType == "Speech reception threshold"
                    if app.tmp.sentIdxInBlock == 1
                        app.tmp.maskerLevel = app.MaskerLevelEditField.Value;
                        app.tmp.SNRvalue = double(string(app.SNRValue.Text));
                    end
                    app.tmp.targetLevel = app.tmp.maskerLevel + app.tmp.SNRvalue;
                else
                    app.tmp.targetLevel = app.TargetLevelEditField.Value;
                    app.tmp.maskerLevel = app.MaskerLevelEditField.Value;
                    app.tmp.SNRvalue = app.tmp.targetLevel - app.tmp.maskerLevel;
                end

            elseif app.AudioSettingsTabs.SelectedTab == app.AdvancedTab

                % Read from randomization file
                if any(ismember(app.tmp.randomizationFile.Properties.VariableNames,'Sentence')) % sentence-based randomization file

                    app.tmp.blockNum = app.tmp.randomizationFile.Block(app.tmp.sentIdxInSession,:);
                    if isfield(app.tmp,'listType')
                        app.tmp = rmfield(app.tmp,'listType');
                        app.tmp = rmfield(app.tmp,'listNum');
                    end
                    app.tmp.sentTotInBlock = nnz(app.tmp.randomizationFile.Block==app.tmp.blockIdxInSession);
                    app.tmp.sentNum = app.tmp.randomizationFile.Sentence(app.tmp.sentIdxInSession,:);
                    if any(ismember(app.tmp.randomizationFile.Properties.VariableNames,'Talker'))
                        app.tmp.target = string(app.tmp.randomizationFile.Talker(app.tmp.sentIdxInSession,:));
                    else
                        app.tmp.target = string(app.tmp.randomizationFile.Target(app.tmp.sentIdxInSession,:));
                    end
                    app.tmp.masker = string(app.tmp.randomizationFile.Masker(app.tmp.sentIdxInSession,:));
                    if any("Target level" == string(app.tmp.randomizationFile.Properties.VariableNames))
                        app.tmp.targetLevel = app.tmp.randomizationFile.("Target level")(app.tmp.sentIdxInSession,:);
                    else
                        error('This is not currently supported');
                    end
                    if any("Masker level" == string(app.tmp.randomizationFile.Properties.VariableNames))
                        app.tmp.maskerLevel = app.tmp.randomizationFile.("Masker level")(app.tmp.sentIdxInSession,:);
                    else
                        error('This is not currently supported');
                    end
                    app.tmp.SNRvalue =  app.tmp.targetLevel -  app.tmp.maskerLevel;

                else % list-based randomization file

                    app.tmp.blockNum = app.tmp.randomizationFile.Block(app.tmp.blockIdxInSession,:);
                    app.tmp.listType = app.tmp.randomizationFile.Properties.VariableNames{...
                        startsWith(app.tmp.randomizationFile.Properties.VariableNames,'List_')...
                        };
                    app.tmp.listNum = app.tmp.randomizationFile.(app.tmp.listType)(app.tmp.blockIdxInSession,:);
                    if any(ismember(app.tmp.randomizationFile.Properties.VariableNames,'Talker'))
                        app.tmp.target = string(app.tmp.randomizationFile.Talker(app.tmp.blockIdxInSession,:));
                    else
                        app.tmp.target = string(app.tmp.randomizationFile.Target(app.tmp.blockIdxInSession,:));
                    end
                    app.tmp.masker = string(app.tmp.randomizationFile.Masker(app.tmp.blockIdxInSession,:));
                    if any("Target level" == string(app.tmp.randomizationFile.Properties.VariableNames))
                        error('This is not currently supported');
                    end
                    if any("Masker level" == string(app.tmp.randomizationFile.Properties.VariableNames))
                        app.tmp.maskerLevel = app.tmp.randomizationFile.("Masker level")(app.tmp.blockIdxInSession,:);
                    end
                    if app.tmp.sentIdxInBlock == 1
                        app.tmp.SNRvalue = 0; % Default starting SNR (TODO Make this editable somewhere)
                    end
                    app.tmp.targetLevel = app.tmp.maskerLevel + app.tmp.SNRvalue;
                    idxSentencesInList = find(app.tmp.(app.tmp.listType).List == app.tmp.listNum);
                    app.tmp.sentTotInBlock = numel(idxSentencesInList); 
                    app.tmp.sentNum = app.tmp.(app.tmp.listType).Sentence(idxSentencesInList(app.tmp.sentIdxInBlock));
                    
                end

            end

            % Update text fields about the specific sentence being presented
            app.tmp.sentText = app.tmp.CorpusInfo.Words(app.tmp.sentNum); % current text sentence
            app.currentSent.Text = sprintf('Sentence %04d (%i/%i)', app.tmp.sentNum, app.tmp.sentIdxInBlock, app.tmp.sentTotInBlock);
            if isfield(app.tmp,'listNum') && ~isempty(app.tmp.listNum)
                app.currentBlock.Text = sprintf('List %i (%i/%i)', app.tmp.listNum, app.tmp.blockIdxInSession, app.tmp.blockTot);
            else
                app.currentBlock.Text = sprintf('Block %i (%i/%i)', app.tmp.blockIdxInSession, app.tmp.blockIdxInSession, app.tmp.blockTot);
            end

            % Reset scoring buttons
            for button = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', button)).Visible = "off";
                app.(sprintf('W%iButton', button)).Value = 0;
                app.(sprintf('W%iButton', button)).FontWeight = 'normal';
                app.(sprintf('W%iButton', button)).Enable = 'on';
            end
            app.tmp.KeywordsButtons = [];
            app.MarkallButton.Value = 0;

            % Update buttons with text and sizing
            buttonSz = 8; % initial button size (wo. words)
            letterSz = 10; % increment for each letter in word
            wordSpacing = 4; % spacing between buttons (words)
            str = string(app.tmp.CorpusInfo.Words(app.tmp.sentNum));
            Words = strsplit(str);
            nWords = numel(Words);
            nLetters = strlength(str);
            app.tmp.Keywords = (app.tmp.CorpusInfo.Keywords(app.tmp.sentNum));
            app.tmp.Keywords = strtrim(strsplit(string(app.tmp.Keywords), ', ')); % divide 3 keywords and remove possible spaces in between words
            centerGrid = app.ScoringPanel.Position(1)+ app.ScoringPanel.Position(3)/2;
            totalSentWidth = buttonSz + wordSpacing + nLetters*(letterSz);
            initPosition = centerGrid - totalSentWidth/2;
            totalSize = initPosition;
            for i = 1:nWords

                % Display each word onto button
                app.(sprintf('W%iButton', i)).Visible = "on";
                app.(sprintf('W%iButton', i)).Text = Words(i);

                % Find keywords and make them bold
                if any(strcmpi(Words(i),app.tmp.Keywords))
                    app.(sprintf('W%iButton', i)).FontWeight = 'bold';
                    app.tmp.KeywordsButtons = [app.tmp.KeywordsButtons i];
                end

                % Adjust button width
                app.(sprintf('W%iButton', i)).Position(3) = buttonSz+(letterSz*strlength(Words(i)));

                % Update button spacing
                app.(sprintf('W%iButton', i)).Position(1) = totalSize;
                totalSize = totalSize + app.(sprintf('W%iButton', i)).Position(3) + wordSpacing; % current size + width of last button + space between words

            end

            % Disable non-keyword buttons (when keyword-based scoring)
            if app.data.Settings.ScoringType == "Keyword-based"
                for m = 1:app.tmp.totButtons
                    if ~any(app.tmp.KeywordsButtons == m)
                        app.(sprintf('W%iButton', m)).Enable = "off";
                    end
                end
            end

            % Update paths to target and masker
            app.tmp.targetFile = sprintf('%04d.wav', app.tmp.sentNum);
            app.tmp.maskerFile = strcat(app.tmp.masker, '.wav');
            app.tmp.targetDir = fullfile(app.data.Settings.AudioPath,'Speech',app.tmp.target);
            app.tmp.maskerDir = fullfile(app.data.Settings.AudioPath,'Noise'); 
            app.tmp.targetFullfile = fullfile(app.tmp.targetDir, app.tmp.targetFile);
            app.tmp.maskerFullfile = fullfile(app.tmp.maskerDir, app.tmp.maskerFile);
            if app.AudioVisualPresentationButton.Value == 1
                app.tmp.videoFile = sprintf('%04d.mp4', app.tmp.sentNum);
                app.tmp.videoDir = fullfile(app.data.Settings.VideoPath,app.tmp.target);
                app.tmp.videoFullfile = fullfile(app.tmp.videoDir, app.tmp.videoFile);
            end

        end

        function initializePlaybackObjects(app)

            % Setting up playback method
            switch app.data.Settings.PlaybackMethod

                case 'Basic'

                    % No setup needed

                case 'AudioToolbox' % Matlab Audio Toolbox

                    % Check if dsp object already exists
                    if ~isempty(app.tmp.playbackObjectPlayer)
                        release(app.tmp.playbackObjectPlayer);
                    end
                    if ~isempty(app.tmp.playbackObjectRecorder)
                        release(app.tmp.playbackObjectRecorder);
                    end

                    % Create the handle to the device writer
                    if ~isempty(app.data.Settings.PlaybackChannelsOut)
                        app.tmp.playbackObjectPlayer = audioDeviceWriter(app.data.Settings.Fs,...
                            'Driver',app.data.Settings.PlaybackDriver,...
                            'ChannelMappingSource','Property',...
                            'ChannelMapping',app.data.Settings.PlaybackChannelsOut,...
                            'BufferSize',app.data.Settings.PlaybackBuffer);
                    end

                    % Create the handle to the device recorder, if relevant
                    if ~isempty(app.data.Settings.PlaybackChannelsIn)
                        app.tmp.playbackObjectRecorder = audioDeviceReader(app.data.Settings.Fs,...
                            'Driver',app.data.Settings.PlaybackDriver,...
                            'ChannelMappingSource','Property',...
                            'ChannelMapping',app.data.Settings.PlaybackChannelsIn,...
                            'SamplesPerFrame',app.data.Settings.PlaybackBuffer);
                    end

                case 'Playrec'

                    % Create the handle
                    HeaAudioPlayRec(...
                        'fs',app.data.Settings.Fs,...
                        'channelsPlay',app.data.Settings.PlaybackChannelsOut,...
                        'channelsRec',app.data.Settings.PlaybackChannelsIn,...
                        'devicePlayID',app.data.Settings.PlaybackDriver);

                    % TODO Implement recording capability

            end

        end
        
        function readSettingsFromInterface(app)

            % Result type
            if app.PercentageCorrectButton.Value == 1
                app.data.Settings.ResultType = "Percent correct";
            else
                app.data.Settings.ResultType = "Speech reception threshold";
                app.data.Settings.AdaptiveMethod = app.AdaptiveMethodDropdown.Value;
                app.data.Settings.StoppingCriteria = app.StoppingCriteriaEditField.Value;
            end

            % Scoring type
            if app.WordByWordButton.Value == 1
                app.data.Settings.ScoringType = "Word-by-word";
            else
                app.data.Settings.ScoringType = "Keyword-based";
            end

            % Presentation mode
            if app.AudioPresentationButton.Value == 1
                app.data.Settings.PresentationMode = "Audio";
            else
                app.data.Settings.PresentationMode = "Audio-visual"; 
            end
            app.data.Settings.AudioPath = app.CurrentAudioDir.Value;
            app.data.Settings.VideoPath = app.CurrentVideoDir.Value;

            % Masker
            if app.IntermittentButton.Value == 1
                app.data.Settings.MaskerSettings = "Intermittent";
                app.data.Settings.DurationBefore = app.DurationbeforeEditField.Value;
            else
                app.data.Settings.MaskerSettings = "Continuous";
            end

            % Playback mode
            if app.MonoButton.Value == 1
                app.data.Settings.PlaybackMode = "Mono";
            elseif app.StereoButton.Value == 1
                app.data.Settings.PlaybackMode = "Stereo";
            else
                app.data.Settings.PlaybackMode = "Multi";
            end

            % Calibration
            app.data.Settings.dBSPLRef = 95; % 95 dB SPL is equivalent to -26 dB FS
            app.data.Settings.dBFSRef = -26;
            app.data.Settings.hannLen = 50e-3*48e3; % 50ms of ramping up and down of the masker (i.e., based on 48 kHz sampling freq)
            app.data.Settings.hannWindow = hann(2*50e-3*48e3);
            app.data.Settings.Fs = 48e3; % Fs = 48 kHz
            app.data.Settings.EqualizationFilterPath = app.EqualizationFilterEditField.Value;
            if ~isempty(app.data.Settings.EqualizationFilterPath)
                load(app.data.Settings.EqualizationFilterPath);
                if exist('EqualizationFilter','var')
                    app.data.Settings.EqualizationFilter = EqualizationFilter;
                else
                    app.data.Settings.EqualizationFilter = equalizationFilter;
                end

            else
                app.data.Settings.EqualizationFilter = [];
            end
            app.data.Settings.CalibrationMeasuredLevel = ...
                [app.LeftMeasuredEditField.Value app.RightMeasuredEditField.Value];

            % Miscellaneous
            app.data.Settings.AdjustSentenceLevelRMS = app.AdjustSentenceLevelRMSCheckbox.Value;
            app.data.Settings.RandomizeOrderWithinLists = app.RandomizeOrderWithinListsCheckbox.Value;
            app.data.Settings.ApplyHearingLossCompensation = app.ApplyHearingLossCompensationCheckbox.Value;
            app.data.Settings.AudiogramFile = app.AudiogramFileEditField;

            % Playback method
            if app.BasicButton.Value == 1
                app.data.Settings.PlaybackMethod = "Basic";
                app.data.Settings.PlaybackDriver = [];
                app.data.Settings.PlaybackChannelsOut = [];
                app.data.Settings.PlaybackChannelsIn = [];
            elseif app.AudioToolboxButton.Value == 1
                app.data.Settings.PlaybackMethod = "AudioToolbox";
                app.data.Settings.PlaybackDriver = app.DriverDropDown.Value;
                app.data.Settings.PlaybackChannelsOut = str2num(string(app.ChannelsOutEditField.Value)); %#ok<ST2NM>
                app.data.Settings.PlaybackChannelsIn = str2num(string(app.ChannelsInEditField.Value)); %#ok<ST2NM>
                app.data.Settings.PlaybackBuffer = 2^14; % hard-coded
            else
                app.data.Settings.PlaybackMethod = "Playrec";
                app.data.Settings.PlaybackDriver = [];
                app.data.Settings.PlaybackChannelsOut = str2num(string(app.ChannelsOutEditField.Value)); %#ok<ST2NM>
                app.data.Settings.PlaybackChannelsIn = str2num(string(app.ChannelsInEditField.Value)); %#ok<ST2NM>
            end
            initializePlaybackObjects(app);

        end

        function writeSettingsToInterface(app)

            % Result type
            if app.data.Settings.ResultType == "Percent correct"
                app.PercentageCorrectButton.Value = 1;
            else
                app.SpeechReceptionThresholdButton.Value = 1;
                app.AdaptiveMethodDropdown.Value = app.data.Settings.AdaptiveMethod;
                app.StoppingCriteriaEditField.Value = app.data.Settings.StoppingCriteria;
            end

            % Scoring type
            if app.data.Settings.ScoringType == "Word-by-word"
                app.WordByWordButton.Value = 1;
            else
                app.KeywordBasedButton.Value = 1;
            end

            % Presentation mode
            if app.data.Settings.PresentationMode == "Audio"
                app.AudioPresentationButton.Value = 1;
            else
                app.AudioVisualPresentationButton.Value = 1;
            end
            app.CurrentAudioDir.Value = app.data.Settings.AudioPath;
            app.CurrentVideoDir.Value = app.data.Settings.VideoPath;

            % Masker
            if app.data.Settings.MaskerSettings == "Intermittent"
                app.IntermittentButton.Value = 1;
                app.DurationbeforeEditField.Value = app.data.Settings.DurationBefore;
            else
                app.ContinuousButton.Value = 1;
            end

            % Playback mode
            if app.data.Settings.PlaybackMode == "Mono"
                app.MonoButton.Value = 1;
            elseif app.data.Settings.PlaybackMode == "Stereo"
                app.StereoButton.Value = 1;
            else
                app.MultiButton.Value = 1;
            end

            % Calibration
            app.EqualizationFilterEditField.Value = app.data.Settings.EqualizationFilterPath;
            app.LeftMeasuredEditField.Value = app.data.Settings.CalibrationMeasuredLevel(1);
            app.RightMeasuredEditField.Value = app.data.Settings.CalibrationMeasuredLevel(2);

            % Miscellaneous
            app.AdjustSentenceLevelRMSCheckbox.Value = app.data.Settings.AdjustSentenceLevelRMS;
            app.RandomizeOrderWithinListsCheckbox.Value = app.data.Settings.RandomizeOrderWithinLists;
            app.ApplyHearingLossCompensationCheckbox.Value = app.data.Settings.ApplyHearingLossCompensation;
            app.AudiogramFileEditField = app.data.Settings.AudiogramFile;

            % Playback method
            if app.data.Settings.PlaybackMethod == "Basic"
                app.BasicButton.Value = 1;
                app.DriverDropDown.Enable = "off";
                app.ChannelsOutEditField.Value = '';
                app.ChannelsInEditField.Value = '';
            elseif app.data.Settings.PlaybackMethod == "AudioToolbox"
                app.AudioToolboxButton.Value = 1;
                app.DriverDropDown.Value = app.data.Settings.PlaybackDriver;
                app.ChannelsOutEditField.Value = num2str(app.data.Settings.PlaybackChannelsOut);
                app.ChannelsInEditField.Value = num2str(app.data.Settings.PlaybackChannelsIn);
            else
                app.PlayrecButton.Value = 1;
                app.DriverDropDown.Enable = "off";
                app.ChannelsOutEditField.Value = num2str(app.data.Settings.PlaybackChannelsOut);
                app.ChannelsInEditField.Value = num2str(app.data.Settings.PlaybackChannelsIn);
            end
            initializePlaybackObjects(app);

            % Call selection change callback functions
            ResultTypeButtonGroupSelectionChanged(app);
            ScoringTypeButtonGroupSelectionChanged(app);
            PresentationModeButtonGroupSelectionChanged(app);
            MaskerButtonGroupSelectionChanged(app);
            PlaybackModeButtonGroupSelectionChanged(app);
            ApplyHearingLossCompensationCheckboxValueChanged(app);
            PlaybackMethodChanged(app);

            % Force update
            drawnow;
            figure(app.UIFigure);

        end

        function loadMixAndPlaySentence(app)

            % Force app to render updates
            drawnow; pause(0.05);

            % Load the target
            loadTarget(app);

            % Load the masker, if relevant
            if app.IntermittentButton.Value == 1
                    loadMasker(app);
            elseif (app.ContinuousButton.Value == 1 && ~isfield(app.tmpm,'maskerSignal'))
                    loadMasker(app);
                    playContinuousMasker(app);
            end

            % Combine target and masker, if relevant
            if app.IntermittentButton.Value == 1
                signalToPlay = app.tmp.targetSignal + app.tmp.maskerSignal;
            else
                signalToPlay = app.tmp.targetSignal;
            end

            % Play the stimuli
            playSignal(app,signalToPlay);

            % Record via Audio Toolbox, if relevant
            if ~isempty(app.data.Settings.PlaybackChannelsIn)
                recordResponse(app);
            end

            % Re-enable continue button
            if app.PauseButton.Value == 1 || app.StopButton.Enable == "off" % if pause activated or end of test
                app.ContinueButton.Enable = "off"; 
            else
                app.ContinueButton.Enable = "on";
            end

        end

        function loadTarget(app)

            % Read the target file
            app.tmp.targetSignal = audioread(app.tmp.targetFullfile);

            % Adjust padding if necessary
            if app.data.Settings.DurationBefore > 0.5

                app.tmp.targetSignal = [zeros((app.data.Settings.DurationBefore-0.5)*app.data.Settings.Fs,1); ...
                    app.tmp.targetSignal(:)];

            end

            % Format, equalization, and scale
            app.tmp.targetSignal = replicateForStereoOrMulti(app,app.tmp.targetSignal);
            app.tmp.targetSignal = applyEqualizationFilter(app,app.tmp.targetSignal);
            if app.AdjustSentenceLevelRMSCheckbox.Value == 1 && isfield(app.tmp,'listNum')
                app.tmp.targetLevelOffset = ...
                    app.tmp.(app.tmp.listType).Offset(app.tmp.(app.tmp.listType).Sentence == app.tmp.sentNum);
            elseif app.AdjustSentenceLevelRMSCheckbox.Value == 1
                app.tmp.targetLevelOffset = mean(app.tmp.CorpusInfo.Inflection_F1A) - ...
                   app.tmp.CorpusInfo.Inflection_F1A(app.tmp.CorpusInfo.Sentence == app.tmp.sentNum);
            else
                app.tmp.targetLevelOffset = 0;
            end
            app.tmp.targetSignal = scaleToDesiredLevel(app,app.tmp.targetSignal,...
                app.tmp.targetLevel+app.tmp.targetLevelOffset);

            % Apply hearing loss compensation, if relevent
            if app.ApplyHearingLossCompensationCheckbox.Value == 1
                error('This functionality does not exist yet.');
                % [cameqgain,~] = cameqGain(Aud,frequencies);
                % cameqfiltercoefs = cvt_design_IG_filter(cameqgain, frequencies, FsTarget);
                % InsertionGain = NALRP( Frequency, HTL, BCL );
                % Target = filter(InsertionGain,1,Target);
            end

        end

        function loadMasker(app)

            % Read info about masker
            app.tmp.maskerInfo = audioinfo(app.tmp.maskerFullfile);
            
            % Read in random segment of masker
            if app.ContinuousButton.Value == 1
                desiredMaskerLength = 5*app.data.Settings.Fs; % NOTE Hard-coded to 5 min
            else
                desiredMaskerLength = length(app.tmp.targetSignal);
            end
            app.tmp.maskerRandSample = round((app.tmp.maskerInfo.TotalSamples-desiredMaskerLength)*rand); % TODO Save in data
            app.tmp.maskerSignal = audioread(app.tmp.maskerFullfile,...
                [app.tmp.maskerRandSample, app.tmp.maskerRandSample+length(app.tmp.targetSignal)-1]);
            
            % Add ramping to masker
            app.tmp.maskerSignal(1:app.data.Settings.hannLen) = app.data.Settings.hannWindow(1:app.data.Settings.hannLen).*app.tmp.maskerSignal(1:app.data.Settings.hannLen);
            app.tmp.maskerSignal(end-app.data.Settings.hannLen+1:end) = app.data.Settings.hannWindow(app.data.Settings.hannLen+1:end).*app.tmp.maskerSignal(end-app.data.Settings.hannLen+1:end);

            % Format, equalization, and scale
            app.tmp.maskerSignal = replicateForStereoOrMulti(app,app.tmp.maskerSignal);
            app.tmp.maskerSignal = applyEqualizationFilter(app,app.tmp.maskerSignal);
            app.tmp.maskerSignal = scaleToDesiredLevel(app,app.tmp.maskerSignal,app.tmp.maskerLevel);

            % Apply hearing loss compensation, if relevent
            if app.ApplyHearingLossCompensationCheckbox.Value == 1
                error('This functionality does not exist yet.');
            end

        end

        function signal = replicateForStereoOrMulti(app,signal)

            % Replicate vectors as needed
            switch app.data.Settings.PlaybackMode
                case 'Mono'
                    % do nothing
                case 'Stereo'
                    signal = repmat(signal,1,2);
%                 case 'Multi'
%                     signal = repmat(signal,1,TODO);
            end

        end

        function signal = applyEqualizationFilter(app,signal)

            % Apply equalization filter, if relevant
            if ~isempty(app.data.Settings.EqualizationFilter)
                for cc = 1:size(app.data.Settings.EqualizationFilter,2)
                    signal(:,cc) = filter(app.data.Settings.EqualizationFilter(:,cc),1,signal(:,cc));
                end
            end

        end

        function signal = scaleToDesiredLevel(app,signal,level)

            % NOTE Assumes signal has a long-term RMS scaled to -26 dB FS

            % Scale the digital levels in each channel to get the desired physical level
            for cc = 1:size(signal,2)

                % Calculate gain (i.e., adjust for desired level correction + calibration offset)
                gain = (level-app.data.Settings.dBSPLRef) + ...
                    (app.data.Settings.dBSPLRef-app.data.Settings.CalibrationMeasuredLevel(cc));
                signal(:,cc) = signal(:,cc)*(10^(gain/20));
                
            end

        end

        function signal = duplicateAcrossChannels(app,signal)

            if size(signal,2)~=length(app.data.Settings.PlaybackChannelsOut)
                switch app.data.Settings.PlaybackMode
                    case 'Mono'
                        signal = repmat(signal, 1, ...
                            length(app.data.Settings.PlaybackChannelsOut));
                    otherwise
                        if mod(length(app.data.Settings.PlaybackChannelsOut),size(signal,2))==0 % duplicate
                            signal = repmat(signal, 1, ...
                                length(app.data.Settings.PlaybackChannelsOut)/size(signal,2));
                        end
                end
            end

        end

        function playSignal(app,signalToPlay)
            
            % If in Basic mode, play using sound function and return
            if strcmp(app.data.Settings.PlaybackMethod,'Basic')

                sound(signalToPlay,app.data.Settings.Fs);
                pause(length(signalToPlay)/app.data.Settings.Fs);
                if app.AudioVisualPresentationButton.Value == 1
                    warning('Audiovisual presentation is not possible using basic playback.');
                end
                return;

            end            

            % Initialize visual part of target stimuli, if relevant
            if app.AudioVisualPresentationButton.Value == 1
                initializeVisualPartOfStimuli(app);
            end

            % Duplicate signalToPlay, if relevant
            signalToPlay = duplicateAcrossChannels(app,signalToPlay);

            % Start acoustic stimuli if using non-blocking playbackMethod
            if strcmp(app.data.Settings.PlaybackMethod,'Playrec')

                % Send signal via playrec
                playrec('play',signalToPlay,app.data.Settings.PlaybackChannelsOut);

                % Pause to align audio and video, if relevant
                if app.AudioVisualPresentationButton.Value == 1 && app.data.Settings.DurationBefore > 0.5
                    pause(app.data.Settings.DurationBefore-0.5);
                end

            end

            % Loop through the stimuli for block-based playback
            app.tmp.playingFlag = true;
            app.tmp.playingIdx = 1;
            while app.tmp.playingFlag==true && app.tmp.playingIdx<size(signalToPlay,1)

                % Send the acoustic part of the buffer, if relevant
                if strcmp(app.data.Settings.PlaybackMethod,'AudioToolbox')
                    playAcousticPartOfBuffer(app,signalToPlay)
                end

                % Send the visual part of the buffer, if relevant
                if app.AudioVisualPresentationButton.Value == 1
                    playVisualPartOfBuffer(app);
                end

                % Drawnow and update buffer index
                drawnow;
                app.tmp.playingIdx = app.tmp.playingIdx+app.data.Settings.PlaybackBuffer;

            end

            % Conclude visual part of stimuli, if relevant
            if app.AudioVisualPresentationButton.Value == 1
                concludeVisualPartOfStimuli(app);
            end

            % Wait until sentence is complete
            pause(length(signalToPlay)/app.data.Settings.Fs);

            % Release audio objects
            releaseAudioObjs(app);

        end

        function playContinuousMasker(app)

            % Start masker, if using non-blocking playbackMethod
            if strcmp(app.data.Settings.PlaybackMethod,'Playrec')

                % Duplicate signalToPlay, if relevant
                signalToPlay = duplicateAcrossChannels(app,app.tmp.maskerSignal);

                % Send signal via playrec
                app.tmp.maskerSignal = playrec('play',signalToPlay,app.data.Settings.PlaybackChannelsOut);

            end

            % Loop through the masker for block-based playback
            if strcmp(app.data.Settings.PlaybackMethod,'AudioToolbox')
                app.tmp.continuousMaskerPlayingFlag = true;
                app.tmp.continuousMaskerPlayingIdx = 1;
                while app.tmp.continuousMaskerPlayingFlag==true && ...
                        app.tmp.continuousMaskerPlayingIdx < size(app.tmp.maskerSignal,1)

                    % Send the acoustic part of the buffer, if relevant
                    playAcousticPartOfBuffer(app,app.tmp.maskerSignal)

                    % Drawnow and update buffer index
                    drawnow;
                    app.tmp.continuousMaskerPlayingIdx = app.tmp.continuousMaskerPlayingIdx+app.data.Settings.PlaybackBuffer;

                end
            end

        end

        function recordResponse(app)

            % Make subfolder for recordings, if relevant
            app.tmp.resultRecordingsDir = fullfile(app.tmp.resultDir,app.tmp.resultName);
            if ~exist(app.tmp.resultRecordingsDir, 'dir')
                mkdir(app.tmp.resultRecordingsDir);
            end

            % Prepare for recording, if relevant
            app.tmp.playbackObjectFileWriter = dsp.AudioFileWriter(...
                fullfile(app.tmp.resultRecordingsDir,app.tmp.targetFile),...
                'FileFormat','WAV',...
                'SampleRate',app.data.Settings.Fs);

            % Enable continue button after end of sentence
            app.ContinueButton.Enable = "on";

            % Record the response, if relevant
            drawnow; pause(0.05);
            tic;
            recordingMaxSec = 60;
            app.tmp.recordingFlag = true;
            while app.tmp.recordingFlag==true && toc < recordingMaxSec
                signalRecorded = app.tmp.playbackObjectRecorder();
                app.tmp.playbackObjectFileWriter(signalRecorded);
                drawnow; % update process for pending callbacks
            end

            % release audio objects
            releaseAudioObjs(app);

        end

        function setupPsychToolbox(app)

            sca;
            close all;
            PsychStartup;
            InitializePsychSound;
            Screen('Preference', 'SkipSyncTests', 2); 
            PsychDefaultSetup(2);
            AssertOpenGL;

            % Get the screen numbers.
            app.tmp.Video.screens = Screen('Screens');
            %Screen('Preference','ConserveVRAM',64); % May be needed for weaker systems

            % Select screen (if external exists chooses external screen)
            screenNumber = max(app.tmp.Video.screens);

            % Open an on-screen window using PsychImaging and color it grey
            [app.tmp.Video.win, windowRect] = PsychImaging('OpenWindow', screenNumber, [189 190 186]/255);
            [screenXpixels, screenYpixels] = Screen('WindowSize', app.tmp.Video.win);
            [xCenter, yCenter] = RectCenter(windowRect);
            Screen('BlendFunction', app.tmp.Video.win, 'GL_SRC_ALPHA', 'GL_ONE_MINUS_SRC_ALPHA');

            % Read Grey Screen Image and display on screen
            imgloc = fullfile(pwd,'files','GreyBackground_Screening_filled.jpg');
            [app.tmp.greyImage, ~, alpha] = imread(imgloc);
            [s1, s2, s3] = size(app.tmp.greyImage);
            app.tmp.Video.imageTexture = Screen('MakeTexture', app.tmp.Video.win, app.tmp.greyImage);
            Screen('Flip',app.tmp.Video.win,[],1);

        end

        function initializeVisualPartOfStimuli(app)

            % Prepare the video part of the stimuli
            app.tmp.Video.movie = Screen('OpenMovie', app.tmp.Video.win, app.tmp.videoFullfile);
            WaitSecs(2);                                                   % TODO Can this be shorter (e.g., 0.1 s)?
            app.tmp.Video.ifi = Screen('GetFlipInterval', app.tmp.Video.win);
            topPriorityLevel = MaxPriority(app.tmp.Video.win); % retreive the maximum priority number
            Priority(topPriorityLevel); % set max priority
            WaitSecs(0.5);                                                 % TODO Can this be shorter (e.g., 0.1 s)?
            app.tmp.Video.vbl = Screen('Flip', app.tmp.Video.win);
            tic;
            [app.tmp.Video.droppedframes] = Screen('PlayMovie', app.tmp.Video.movie, 1);

        end

        function playAcousticPartOfBuffer(app,signalToPlay)

            % Buffer
            if (app.tmp.playingIdx+app.data.Settings.PlaybackBuffer-1)<size(signalToPlay,1)
                signalToPlayBuffered = signalToPlay(app.tmp.playingIdx:(app.tmp.playingIdx+app.data.Settings.PlaybackBuffer-1),:);
            else
                zeroPadLen = app.data.Settings.PlaybackBuffer-size(signalToPlay(app.tmp.playingIdx:end,:),1);
                signalToPlayBuffered = [signalToPlay(app.tmp.playingIdx:end,:);
                    zeros(zeroPadLen,size(signalToPlay,2))];
            end

            % Send via playback object
            app.tmp.playbackObjectPlayer(signalToPlayBuffered);

        end
 
        function playVisualPartOfBuffer(app)

            % Wait for next movie frame, retrieve texture handle to it
            tex = Screen('GetMovieImage', app.tmp.Video.win, app.tmp.Video.movie);

            % Break if movie is done (i.e., if texture is invalid)
            if tex <= 0
                return;
            end

            % Draw the new texture immediately to screen
            Screen('DrawTexture', app.tmp.Video.win, tex);

            % Update the display
            Screen('Flip', app.tmp.Video.win, 0);

            % Release the texture
            Screen('Close', tex);

        end

        function concludeVisualPartOfStimuli(app)

            % Stop playback
            Screen('PlayMovie', app.tmp.Video.movie, 0);

            % Close movie
            Screen('CloseMovie', app.tmp.Video.movie);
            Screen('DrawTexture', app.tmp.Video.win, app.tmp.Video.imageTexture);
            Screen('Flip', app.tmp.Video.win,[], 1);
            if app.tmp.sentIdxInBlock == app.tmp.sentTotInBlock
                Screen('MakeTexture', app.tmp.Video.win, app.tmp.greyImage);
                Screen('Flip',app.tmp.Video.win,[],1);
            end
            FlushEvents();  

        end

        function releaseAudioObjs(app)

            % Release all existing audio objects
            clear sound
            app.tmp.recordingFlag = false;
            app.tmp.playingFlag = false;
            if isfield(app.data.Settings,'playbackObjectPlayer') && ~isempty(app.tmp.playbackObjectPlayer)
                release(app.tmp.playbackObjectPlayer);
            end
            if isfield(app.data.Settings,'playbackObjectRecorder') && ~isempty(app.tmp.playbackObjectRecorder)
                release(app.tmp.playbackObjectRecorder);
            end
            if isfield(app.data.Settings,'playbackObjectFileWriter') && ~isempty(app.tmp.playbackObjectFileWriter)
                release(app.tmp.playbackObjectFileWriter);
            end

        end

        function saveSentenceLevelPartialResultsInFile(app)

            % Save word-by-word results, if relevant
            if app.data.Settings.ScoringType == "Word-by-word"
               
                wordsDisplay = strsplit(string(app.tmp.sentText));
                numWordsSent = numel(wordsDisplay);
                app.tmp.WordScore = zeros(1,numWordsSent);
                for i = 1:app.tmp.totButtons
                    if app.(sprintf('W%iButton', i)).Value == 1
                        app.tmp.WordScore(i) = 1; % Read out words correctly repeated
                    end
                end
                for w = 1:numWordsSent
                    app.data.WordScore(app.tmp.sentIdxInSession, w) = ...
                        app.tmp.WordScore(w);
                end
                for w = (numWordsSent+1):app.tmp.totButtons
                    app.data.WordScore(app.tmp.sentIdxInSession, w) = NaN;
                end
            end

            % Save keyword-by-keyword results
            numKeywordsSent = numel(app.tmp.Keywords);
            app.tmp.KeywordScore = zeros(1,numKeywordsSent);
            for j = 1:length(app.tmp.KeywordsButtons)
                if app.(sprintf('W%iButton', app.tmp.KeywordsButtons(j))).Value == 1
                    app.tmp.KeywordScore(j) = 1; % Read out keywords correctly repeated
                end
            end
            for w = 1:numKeywordsSent
                app.data.KeywordScore(app.tmp.sentIdxInSession, w) = ...
                    app.tmp.KeywordScore(w);
            end

            % Calculate score for current sentence
            if app.data.Settings.ScoringType == "Word-by-word"
                numCorrectWords = sum(app.tmp.WordScore,'omitnan');
                app.tmp.SentenceScore = (numCorrectWords/numWordsSent)*100;
            else
                numCorrectWords = sum(app.tmp.KeywordScore);
                app.tmp.SentenceScore = (numCorrectWords/3)*100;
            end
            app.data.SentenceScore(app.tmp.sentIdxInSession,1) = app.tmp.SentenceScore;

            % Write this and other important info to data struct
            if ~isfield(app.data,'SentenceInfo') % initialize if it doesn't exist
                varTypes = ["double", "double", "cell", "cell", "double", "double"];
                varNames = ["Block", "Sentence", "Target", "Masker", "Target level", "Masker level"];
                sz = [1, length(varNames)];
                app.data.SentenceInfo = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            end
            if app.tmp.sentIdxInSession > 1 % hack to initialize and avoid warning
                app.data.SentenceInfo = vertcat(app.data.SentenceInfo,app.data.SentenceInfo(1,:));
            end
            app.data.SentenceInfo.Block(app.tmp.sentIdxInSession) = app.tmp.blockIdxInSession;
            app.data.SentenceInfo.Sentence(app.tmp.sentIdxInSession) = app.tmp.sentNum;
            app.data.SentenceInfo.Target(app.tmp.sentIdxInSession) = cellstr(app.tmp.target);
            app.data.SentenceInfo.Masker(app.tmp.sentIdxInSession) = cellstr(app.tmp.masker);
            app.data.SentenceInfo.("Target level")(app.tmp.sentIdxInSession) = app.tmp.targetLevel;
            app.data.SentenceInfo.("Masker level")(app.tmp.sentIdxInSession) = app.tmp.maskerLevel;

            % Save mat file
            data = app.data; %#ok<ADPROP> 
            save(app.tmp.resultFullfile, 'data', '-mat');

        end

        function saveBlockLevelPartialResultsInFile(app)

            % Store block-level scores
            app.data.BlockScore(app.tmp.sentIdxInSession,1) = app.tmp.BlockScore;
            app.data.BlockVariability(app.tmp.sentIdxInSession,1) = app.tmp.BlockVariability;

            % Save mat file
            data = app.data; %#ok<ADPROP> 
            save(app.tmp.resultFullfile, 'data', '-mat');

        end

        function measurementComplete = adjustSNRDefaultMethod(app) % Inspired by Keidser et al. 2013 (International Journal of Audiology 2013; 52: 795–800)

            % Initialize helper functions
            findFirstReversal = @(x) find(diff(x)>=0,1);
            findStartPhaseTwo = @(x) find(abs(diff(x))==2,1);
            getIdxs = @(N) (app.tmp.sentIdxInSession-N+1):app.tmp.sentIdxInSession;
            getSNRs = @(N) app.data.SentenceInfo.("Target level")(getIdxs(N)) - ...
                app.data.SentenceInfo.("Masker level")(getIdxs(N));

            % Initialize flag
            measurementComplete = false;

            % Update SRT estimates and standard error
            listOfSNRs = getSNRs(app.tmp.sentIdxInBlock);
            if ~isempty(findStartPhaseTwo(listOfSNRs))
                app.tmp.BlockVariability = 2*std(listOfSNRs(findStartPhaseTwo(listOfSNRs):end))/...
                    sqrt(app.tmp.sentIdxInBlock-findStartPhaseTwo(listOfSNRs));
                app.tmp.BlockScore = mean(listOfSNRs(findStartPhaseTwo(listOfSNRs):end));
            else
                app.tmp.BlockVariability = NaN;
                app.tmp.BlockScore = NaN;
            end

            % Adjust SNR according to phase
            if ... % >>> Phase 0 <<<
                (app.tmp.sentIdxInBlock == 1) && ...
                (app.tmp.SentenceScore < 100)

                app.tmp.targetLevel = app.tmp.targetLevel + 5;
                app.tmp.SNRvalue = app.tmp.targetLevel - app.tmp.maskerLevel;

            elseif ... % >>> Phase 1 <<<
                (app.tmp.sentIdxInBlock < 4) || ...
                isempty(findFirstReversal(listOfSNRs))
                
                if app.tmp.SentenceScore > 50
                    app.tmp.SNRvalue = app.tmp.SNRvalue - 5;
                elseif app.tmp.SentenceScore == 50
                    app.tmp.SNRvalue = app.tmp.SNRvalue;
                else
                    app.tmp.SNRvalue = app.tmp.SNRvalue + 5;
                end

            elseif ...  % >>> Phase 2 <<<
                (abs(listOfSNRs(end)-listOfSNRs(end-1)) == 5) || ...
                (app.tmp.sentIdxInBlock < findStartPhaseTwo(listOfSNRs)+4) || ...
                (app.tmp.BlockVariability > 1)
                
                if app.tmp.SentenceScore > 50
                    app.tmp.SNRvalue = app.tmp.SNRvalue - 2;
                elseif app.tmp.SentenceScore == 50
                    app.tmp.SNRvalue = app.tmp.SNRvalue;
                else
                    app.tmp.SNRvalue = app.tmp.SNRvalue + 2;
                end
            
            elseif ... % >>> Phase 3 <<<
                (app.tmp.sentIdxInBlock <= 20) && ...
                ((abs(listOfSNRs(end)-listOfSNRs(end-1)) == 2) || ...
                (app.tmp.sentIdxInBlock < findStartPhaseTwo(listOfSNRs)+8) || ...
                app.tmp.BlockVariability > app.data.Settings.StoppingCriteria)
                
                if app.tmp.SentenceScore > 50
                    app.tmp.SNRvalue = app.tmp.SNRvalue - 1;
                elseif app.tmp.SentenceScore == 50
                    app.tmp.SNRvalue = app.tmp.SNRvalue;
                else
                    app.tmp.SNRvalue = app.tmp.SNRvalue + 1;
                end
            
            else % SRT measurement complete
                
                measurementComplete = true;
            
            end

        end

        function updateProgressPlots(app)

            % Initialize helper functions
            findStartPhaseTwo = @(x) find(abs(diff(x))==2,1);
            findStartPhaseThree = @(x) find(abs(diff(x))==1,1);
            getIdxs = @(N) (app.tmp.sentIdxInSession-N+1):app.tmp.sentIdxInSession;
            getSNRs = @(N) app.data.SentenceInfo.("Target level")(getIdxs(N)) - ...
                app.data.SentenceInfo.("Masker level")(getIdxs(N));
            listOfSNRs = getSNRs(app.tmp.sentIdxInBlock);

            % >>> SentIdxVsSNR <<<
            % Plot SentIdxVsSNR
            app.SentIdxVsSNR.Visible = "on";
            hold(app.SentIdxVsSNR,'off');
            plot(app.SentIdxVsSNR,...
                1:app.tmp.sentIdxInBlock,... % x-values
                listOfSNRs,... % y-values
                '-o',...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','k',...
                'Color','k',...
                'LineWidth',2.5);
            hold(app.SentIdxVsSNR,'on');
            set(app.SentIdxVsSNR,'XLim',[0 21]);
            if app.data.Settings.ResultType == "Speech reception threshold" && ...
                    (app.tmp.sentIdxInBlock == 1) && ...
                    app.tmp.SentenceScore < 100
                xval = app.tmp.sentIdxInBlock;
            else
                xval = app.tmp.sentIdxInBlock + 1;
            end
            plot(app.SentIdxVsSNR,...
                xval,... % x-values
                app.tmp.SNRvalue,... % y-values
                'o',...
                'Color',0.7*ones(1,3),...
                'LineWidth',2.5,...
                'LineStyle','none');
            set(app.SentIdxVsSNR,'YLim',...
                [min([min([listOfSNRs;app.tmp.SNRvalue])-2 -15])...
                max([max([listOfSNRs;app.tmp.SNRvalue])+2 +5])]);

            % Add vertical line to SentIdxVsSNR once in Phase 2
            if app.data.Settings.ResultType == "Speech reception threshold" && ...
                    ~isempty(findStartPhaseTwo([listOfSNRs;app.tmp.SNRvalue])) && ...
                    (app.tmp.sentIdxInBlock >= findStartPhaseTwo([listOfSNRs;app.tmp.SNRvalue]))
                
                plot(app.SentIdxVsSNR,...
                    findStartPhaseTwo([listOfSNRs;app.tmp.SNRvalue])*ones(2,1),... % x-values
                    [-40 40],... % y-values
                    ':k',...
                    'LineWidth',1.0);

            end

            % Add SRT estimate and error to SentIdxVsSNR
            if app.data.Settings.ResultType == "Speech reception threshold" && ...
                    ~isnan(app.tmp.BlockScore)
                
                plot(app.SentIdxVsSNR,...
                    [0 21],... % x-values
                    app.tmp.BlockScore*ones(1,2),... % y-value
                    '-k',...
                    'LineWidth',2.0);
                plot(app.SentIdxVsSNR,...
                    [0 21],... % x-values
                    (app.tmp.BlockScore+app.tmp.BlockVariability)*ones(1,2),... % y-value
                    '-.k',...
                    'LineWidth',1.5);
                plot(app.SentIdxVsSNR,...
                    [0 21],... % x-values
                    (app.tmp.BlockScore-app.tmp.BlockVariability)*ones(1,2),... % y-value
                    '-.k',...
                    'LineWidth',1.5);
                text(app.SentIdxVsSNR,...
                    max(get(app.SentIdxVsSNR,'Xlim')),...
                    min(get(app.SentIdxVsSNR,'Ylim'))-0.20*diff(get(app.SentIdxVsSNR,'Ylim')),...
                    sprintf('(SE: %1.1f dB)',app.tmp.BlockVariability),...
                    'HorizontalAlignment','Right',...
                    'FontSize',10,...
                    'FontWeight','bold');

            end

            % Add second vertical line to SentIdxVsSNR once in Phase 3
            if app.data.Settings.ResultType == "Speech reception threshold" && ...
                    ~isempty(findStartPhaseThree([listOfSNRs;app.tmp.SNRvalue])) && ...
                    (app.tmp.sentIdxInBlock >= findStartPhaseThree([listOfSNRs;app.tmp.SNRvalue]))
                
                plot(app.SentIdxVsSNR,...
                    findStartPhaseThree([listOfSNRs;app.tmp.SNRvalue])*ones(2,1),... % x-values
                    [-40 40],... % y-values
                    ':k',...
                    'LineWidth',1.0);

            end
            
            % >>> SNRVsPercent <<<
            % Add new SNR to SNRVsPercent
            app.SNRVsPercent.Visible = "on";
            hold(app.SNRVsPercent,'off');
            scatter(app.SNRVsPercent,...
                listOfSNRs,... % x-values
                app.data.SentenceScore(getIdxs(app.tmp.sentIdxInBlock)),... % y-values
                'filled',...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor','k');
            hold(app.SNRVsPercent,'on');
            if (app.data.Settings.ResultType == "Percent correct") && numel(unique(listOfSNRs)) == 1
                alpha(app.SNRVsPercent,0.1);
            end
            set(app.SNRVsPercent,'XLim',...
                [min([min([listOfSNRs;app.tmp.SNRvalue])-2 -15])...
                max([max([listOfSNRs;app.tmp.SNRvalue])+2 +5])]);
            set(app.SNRVsPercent,'YLim',[-10 110]);
            
            % Add psychometric function estimate to SNRVsPercent
            if ~isnan(app.tmp.BlockScore)
                
                if app.data.Settings.ResultType == "Speech reception threshold"
                    plot(app.SNRVsPercent,...
                        app.tmp.BlockScore*ones(1,2),... % x-values
                        [-10 110],... % y-values
                        '-k',...
                        'LineWidth',2.0);
                    plot(app.SNRVsPercent,...
                        (app.tmp.BlockScore+app.tmp.BlockVariability)*ones(1,2),... % x-values
                        [-10 110],... % y-values
                        '-.k',...
                        'LineWidth',1.5);
                    plot(app.SNRVsPercent,...
                        (app.tmp.BlockScore-app.tmp.BlockVariability)*ones(1,2),... % x-values
                        [-10 110],... % y-values
                        '-.k',...
                        'LineWidth',1.5);
                    text(app.SNRVsPercent,...
                        max(get(app.SNRVsPercent,'Xlim')),...
                        min(get(app.SNRVsPercent,'Ylim'))-0.20*diff(get(app.SNRVsPercent,'Ylim')),...
                        sprintf('(SRT: %1.1f dB)',app.tmp.BlockScore),...
                        'HorizontalAlignment','Right',...
                        'FontSize',10,...
                        'FontWeight','bold');
                else
                    plot(app.SNRVsPercent,...
                        get(app.SNRVsPercent,'Xlim'),... % x-values
                        app.tmp.BlockScore*ones(1,2),... % y-values
                        '-k',...
                        'LineWidth',2.0);
                    plot(app.SNRVsPercent,...
                        get(app.SNRVsPercent,'Xlim'),... % x-values
                        (app.tmp.BlockScore+app.tmp.BlockVariability)*ones(1,2),... % y-values
                        '-.k',...
                        'LineWidth',1.5);
                    plot(app.SNRVsPercent,...
                        get(app.SNRVsPercent,'Xlim'),... % x-values
                        (app.tmp.BlockScore-app.tmp.BlockVariability)*ones(1,2),... % y-values
                        '-.k',...
                        'LineWidth',1.5);
                    text(app.SNRVsPercent,...
                        max(get(app.SNRVsPercent,'Xlim')),...
                        min(get(app.SNRVsPercent,'Ylim'))-0.20*diff(get(app.SNRVsPercent,'Ylim')),...
                        sprintf('(Score: %1.1f%%)',app.tmp.BlockScore),...
                        'HorizontalAlignment','Right',...
                        'FontSize',10,...
                        'FontWeight','bold');
                end
                if app.data.Settings.ResultType == "Speech reception threshold" || ...
                        (app.data.Settings.ResultType == "Percent correct" && numel(unique(listOfSNRs)) > 1)
                    fitFunc = @(c,vals) 100./( 1 + exp(-4*(c(2)/100)*(vals-c(1))) );
                    fitCoeffs = nlinfit(listOfSNRs,...
                        app.data.SentenceScore(getIdxs(app.tmp.sentIdxInBlock)),...
                        fitFunc,...
                        [app.tmp.BlockScore 20]);
                    xvals = linspace(min(xlim(app.SNRVsPercent)),max(xlim(app.SNRVsPercent)),100);
                    plot(app.SNRVsPercent,...
                        xvals,...
                        fitFunc(fitCoeffs,xvals),...
                        'Color','k',...
                        'LineWidth',2.0);
                end

            end

        end

        function saveFinalResultsInFile(app)

            if app.BasicInformationPanel.Enable == "on"
                % if user pressed Stop and going back to test setup
                app.tmp.sentIdxInSession = app.tmp.sentIdxInSession - 1;
            end
            if app.tmp.sentIdxInSession <= 0
                % do nothing (start of test)
            elseif app.tmp.sentIdxInBlock == app.tmp.sentTotInBlock || app.tmp.blockIdxInSession == app.tmp.blockTot % end of the block/list/test

                % Save final mat file
                data = app.data; %#ok<ADPROP> 
                save(app.tmp.resultFullfile, 'data', '-mat')
            end

        end

        function disableButtons_initialState(app)

            % User input enabled
            app.StartButton.Enable = "on";
            app.BasicInformationPanel.Enable = "on";
            app.Panel.Enable = "on";
            app.SettingsPanelinSettingsTab.Enable = "on";

            % Test tab disabled
            app.PauseButton.Enable = "off";
            app.StopButton.Enable = "off";
            app.NextButton.Enable = "off";
            app.MarkallButton.Enable = "off";

            app.tmp.KeywordsButtons =[];
            app.MarkallButton.Value = 0;
            app.PauseButton.Value = 0;

            for i = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', i)).Visible = "off";
                app.(sprintf('W%iButton', i)).Value = 0;
                app.(sprintf('W%iButton', i)).FontWeight = 'normal';
            end

            app.MarkallButton.Visible = "off";
            app.NextButton.Visible = "off";
            app.currentBlock.Visible = "off";
            app.currentSent.Visible = "off";
            app.ContinueButton.Visible = "off";

            % Erase data in progress plots and make invisible
            cla(app.SentIdxVsSNR);
            cla(app.SNRVsPercent);
            app.SentIdxVsSNR.Visible = "off";
            app.SNRVsPercent.Visible = "off";

        end

        function enableButtons_testState(app)

            % User input disabled
            app.StartButton.Enable = "off";
            app.BasicInformationPanel.Enable = "off";
            app.Panel.Enable = "off";
            app.SettingsPanelinSettingsTab.Enable = "off";

            % Test tab enabled
            app.PauseButton.Enable = "on";
            app.StopButton.Enable = "on";
            app.MarkallButton.Enable = "on";
            app.ContinueButton.Enable = "off";
            for i = 1:app.tmp.totButtons
                app.(sprintf('W%iButton', i)).Visible = "on";
                app.(sprintf('W%iButton', i)).Enable = "on";
            end
            app.MarkallButton.Visible = "on";
            app.ContinueButton.Visible = "on";
            app.Instructions.Visible = "on";
            app.NextButton.Visible = "off";
            app.currentBlock.Visible = "on";
            app.currentSent.Visible = "on";
            app.Instructions.Text = 'Mark correctly repeated words';

            % Erase data in progress plots and make visible
            cla(app.SentIdxVsSNR);
            cla(app.SNRVsPercent);
            app.SentIdxVsSNR.Visible = "on";
            app.SNRVsPercent.Visible = "on";

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % intialize variables
            app.data = [];
            app.tmp = [];

            % Shuffle randomization
            rng('shuffle');

            % Intializing variables
            [currentfilepath,~,~] = fileparts(mfilename('fullpath'));
            if contains(currentfilepath, "DAST.app") % for executable version
                currentfilepath = extractBefore(currentfilepath, "DAST.app");
            end
            cd (currentfilepath)

            formatOut = 'yyyy_mm_dd';
            app.tmp.Date = datestr(now,formatOut);
            app.FilenameEditField.Value = sprintf(app.tmp.Date);

            % Audio and video directories
            app.CurrentAudioDir.Value = fullfile(pwd, 'audio');
            app.CurrentVideoDir.Value = fullfile(pwd, 'video');

            % Make subfolder for presets, if relevant
            app.tmp.presetDir = fullfile(pwd, 'presets');
            if ~exist(app.tmp.presetDir, 'dir')
                mkdir(app.tmp.presetDir);
            end

            % Make subfolder for results, if relevant
            app.tmp.resultDir = fullfile(pwd, 'results');
            if ~exist(app.tmp.resultDir, 'dir')
                mkdir(app.tmp.resultDir);
            end

            % Read in DAST Sentences
            opts = detectImportOptions(fullfile(pwd, 'files', 'DAST_corpus_information.csv'));
            opts.Encoding = 'UTF-8';
            opts.VariableNamingRule = 'Preserve';
            app.tmp.CorpusInfo = readtable(fullfile(pwd, 'files', 'DAST_corpus_information.csv'));

            % Read in DAST List(s)
            list_files = dir(fullfile(pwd, 'files', 'DAST_List_*.csv'));
            if ~isempty(list_files)
                app.tmp.ListTypes = cell(numel(list_files),1);
                for ff = 1:numel(list_files)
                    app.tmp.ListTypes{ff} = list_files(ff).name(6:end-4);
                    opts = detectImportOptions(fullfile(pwd, 'files', list_files(ff).name));
                    opts.Encoding = 'UTF-8';
                    opts.VariableNamingRule = 'Preserve';
                    app.tmp.(app.tmp.ListTypes{ff}) = readtable(fullfile(pwd, 'files', list_files(ff).name));
                end
                app.ListTypeDropDown.Items = app.tmp.ListTypes;
                app.ListTypeDropDown.Value = app.tmp.ListTypes{strcmp(app.tmp.ListTypes,'List_F1A')}; % default
                ListTypeDropDownValueChanged(app);
                app.ListTypeDropDown.Enable = 1;
                app.ListNumberDropDown.Enable = 1;
                app.ListNumberDropDown.Enable = 1;
            end

            % Keywords and Buttons
            app.tmp.KeywordsButtons = [];
            app.tmp.totButtons = 12;

            % Disable buttons
            disableButtons_initialState(app)
            app.tmp.sentIdxInSession = -1;
            app.StartingBlockDropDown.Visible = "off";
            app.StartingblockDropDownLabel.Visible = "off";

            % Playback setup
            app.tmp.playbackObjectPlayer = [];
            app.tmp.playbackObjectRecorder = [];
            app.tmp.playbackObjectFileWriter = [];
            app.tmp.recordingFlag = false;
            app.tmp.playingFlag = false;

            % Grabs default settings from "Settings" tab
            if ispc % if windows
                app.DriverDropDown.Items = {'ASIO', 'DirectSound', 'WASAPI'};
            elseif ismac % if mac
                app.DriverDropDown.Items = {'CoreAudio'};
            else % if linux (isunix)
                app.DriverDropDown.Items = {'ALSA'};
            end
            readSettingsFromInterface(app);
            app.ApplySettingsButton.Enable = "off";
            app.SavePresetButton.Enable = "off";

        end

        % Value changed function: FilenameEditField, 
        % ...and 2 other components
        function TestInfoChanged(app, event)
            if isempty(app.TesterIDEditField.Value); Leader = '';
            else; Leader = strcat('_', app.TesterIDEditField.Value); end
            if isempty(app.ParticipantIDEditField.Value); Subject = '';
            else; Subject = strcat(app.ParticipantIDEditField.Value, '_'); end

            app.FilenameEditField.Value = sprintf(strcat(Subject, app.tmp.Date, Leader));
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            % Update user interface
            enableButtons_testState(app);

            % Reset data struct
            app.data = [];

            % Copy settings to data struct
            readSettingsFromInterface(app); 
            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab
                app.data.Settings.ListType = app.ListTypeDropDown.Value;
                app.data.Settings.ListNumber = str2double(app.ListNumberDropDown.Value);
            end

            % Reinitialize tmp struct
            app.tmp.SentenceScore = [];
            app.tmp.BlockScore = NaN;
            app.tmp.BlockVariability = NaN;
            app.tmp.sentIdxInBlock = 0; % current sentence
            app.tmp.sentIdxInSession = 0; % initializing line for saving results
            if app.AudioSettingsTabs.SelectedTab == app.QuickstartTab
                app.tmp.blockIdxInSession = 1; 
                app.tmp.blockTot = 1;
            else
                app.tmp.blockIdxInSession = find(app.tmp.randomizationFile.Block == double(string(app.StartingBlockDropDown.Value)),1,'first'); % current list from dropdown
            end

            % Erase data in progress plots
            cla(app.SentIdxVsSNR);
            cla(app.SNRVsPercent);

            % Update file locations and names
            updateInfoForNextSent(app);

            % Make result filename (i.e., add suffix if necessary)
            app.tmp.resultName = app.FilenameEditField.Value;
            app.tmp.resultFullfile = fullfile(app.tmp.resultDir, [app.tmp.resultName, '.mat']);
            newName = app.tmp.resultName;
            for i = 1:30
                if isfile(app.tmp.resultFullfile)
                    newName = strcat(app.tmp.resultName, '.', num2str(i));
                    app.tmp.resultFullfile = fullfile(app.tmp.resultDir, [newName, '.mat']);
                else
                    break;
                end
            end
            if strcmp(app.tmp.resultName,newName) == 0
                app.tmp.resultName = newName;
            end

            % Set up visual component, if relevant
            if app.AudioVisualPresentationButton.Value == 1
                setupPsychToolbox(app);
            end

            % Load, mix, and play the sentence
            loadMixAndPlaySentence(app);

        end

        % Value changed function: MarkallButton
        function MarkallButtonValueChanged(app, event)
            value = app.MarkallButton.Value;
            for i = 1:app.tmp.totButtons
                if app.(sprintf('W%iButton', i)).Visible == "on"
                    if (value == 1 && app.data.Settings.ScoringType ~= "Keyword-based") ||...
                            (value == 1 && app.data.Settings.ScoringType == "Keyword-based" && any(app.tmp.KeywordsButtons == i))
                        app.(sprintf('W%iButton', i)).Value = 1;
                    else
                        app.(sprintf('W%iButton', i)).Value = 0;
                    end
                end
            end
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)

            % Disable Continue Button to avoid double-pressing
            app.ContinueButton.Enable = "off";

            % Release audio objects
            releaseAudioObjs(app);

            % Save sentence-level results
            saveSentenceLevelPartialResultsInFile(app);

            % Calculate score estimates, the next SNR, and stopping criteria, if relevant
            if app.data.Settings.ResultType == "Speech reception threshold"
                switch app.data.Settings.AdaptiveMethod
                    case 'Default'
                        criteriaMet = adjustSNRDefaultMethod(app);
%                     case 'Quick'
%                         criteriaMet = adjustSNRQuickMethod(app);
                end
            else
                app.tmp.BlockScore = mean(app.data.SentenceScore(...
                    app.data.SentenceInfo.Block==app.tmp.blockIdxInSession));
                app.tmp.BlockVariability= std(app.data.SentenceScore(...
                    app.data.SentenceInfo.Block==app.tmp.blockIdxInSession));
                criteriaMet = false;
            end

            % Save block-level results
            saveBlockLevelPartialResultsInFile(app);

            % Update progress plots
            updateProgressPlots(app);

            % Proceed onto the next relevant step
            if app.data.Settings.ResultType == "Speech reception threshold" && ...
                    (app.tmp.sentIdxInBlock == 1) && ...
                    app.tmp.SentenceScore < 100 % repeat sentence

                % Load, mix, and play the next sentence
                loadMixAndPlaySentence(app);

            elseif (app.tmp.sentIdxInBlock < app.tmp.sentTotInBlock) && ~criteriaMet % go to next sentence

                % Update file locations/names
                updateInfoForNextSent(app);

                % Load, mix, and play the next sentence
                loadMixAndPlaySentence(app);

            elseif (app.tmp.blockIdxInSession < app.tmp.blockTot) || criteriaMet % go to next block

                % Stop continuous masker, if relevant
                if app.ContinuousButton.Value == 1
                    delPage(app.tmp.maskerSignal); % stop playrec
                end

                % Save results for list
                saveFinalResultsInFile(app);

                % Going to next block by pressing button
                app.ContinueButton.Enable = "off";
                drawnow; pause(0.05);
                app.NextButton.Visible = "on";
                app.NextButton.Enable = "on";
                app.tmp.SentenceScore = [];
                app.tmp.BlockScore = NaN;
                app.tmp.BlockVariability = NaN;
                app.Instructions.Text = ['Block ', num2str(app.tmp.blockIdxInSession), ' done'];

                % Make all buttons invisible
                for button = 1:app.tmp.totButtons
                    app.(sprintf('W%iButton', button)).Visible = "off";
                end
                app.PauseButton.Enable = "off";
                app.MarkallButton.Visible = "off";

            else % session complete

                % Save final results
                saveFinalResultsInFile(app);

                % End of test
                disableButtons_initialState(app);
                drawnow; pause(0.05);
                app.Instructions.Text = 'Done';

            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)

            % Stop continuous masker, if relevant
            if app.ContinuousButton.Value == 1
                delPage(app.tmp.maskerSignal); % stop playrec
            end

            % Release audio and video objects
            releaseAudioObjs(app)
            if app.AudioVisualPresentationButton == 1
                sca;
            end

            % Disable buttons
            disableButtons_initialState(app)

            app.Instructions.Text = 'Test terminated';

            % Save results for list/end of test
            saveFinalResultsInFile(app);

        end

        % Value changed function: PauseButton
        function PauseButtonValueChanged(app, event)

            if app.PauseButton.Value == 1 % Pausing

                % Pause continuous masker, if relevant
                if app.ContinuousButton.Value == 1
                    pause(app.tmp.maskerSignal);
                end

                % Release audio objects
                releaseAudioObjs(app);

                % Save state of buttons while updating the interface
                app.Instructions.Text = 'Test paused';
                app.tmp.activeButtons = zeros(1, app.tmp.totButtons);
                for button = 1:app.tmp.totButtons
                    if app.(sprintf('W%iButton', button)).Visible == "on"
                        app.tmp.activeButtons(button) = 1;
                    end
                    app.(sprintf('W%iButton', button)).Visible = "off";
                end
                app.ContinueButton.Enable = "off";
                app.MarkallButton.Visible = "off";

            else % Resume

                % Resume the continuous masker, if relevant
                if app.ContinuousButton.Value == 1
                    pause(app.tmp.maskerSignal);
                end

                % Update interface
                for button = 1:app.tmp.totButtons
                    if app.tmp.activeButtons(button) == 1
                        app.(sprintf('W%iButton', button)).Visible = "on";
                    end
                end
                app.MarkallButton.Visible = "on";
                app.Instructions.Text = 'Mark correctly repeated words';
                app.Instructions.Visible = "on";

                % Load, mix, and play the sentence again
                loadMixAndPlaySentence(app);
                
            end

        end

        % Value changed function: W1Button, W2Button, W3Button, W4Button, 
        % ...and 4 other components
        function wordSelected(app, event)
            values = zeros(1,app.tmp.totButtons);
            for i = 1:app.tmp.totButtons
                values(i) =  app.(sprintf('W%iButton', i)).Value;
            end
            if any(values == 0)
                app.MarkallButton.Value = 0;
            end

        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            [filename,pathname] = uigetfile(fullfile(pwd,'files','*.csv'));
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                try
                    app.CSVFileEditField.Value = fullfile(pathname,filename);
                    app.UploadedCSVCheckBox.Value = 1;
                    app.StartingblockDropDownLabel.Visible = "on";
                    app.StartingBlockDropDown.Visible = "on";

                    % Read from csv file
                    opts = detectImportOptions(app.CSVFileEditField.Value);
                    opts.Encoding = 'UTF-8';
                    opts.VariableNamingRule = 'Preserve';
                    app.tmp.randomizationFile = readtable(app.CSVFileEditField.Value, opts);  % uploaded csv with test info
                    
                    % Update block total
                    app.tmp.blockTot = length(unique(app.tmp.randomizationFile.Block, 'stable'));

                    % Showing list options in menu
                    app.StartingBlockDropDown.Items = cellstr(num2str(unique(app.tmp.randomizationFile.Block, 'stable')));

                    drawnow;
                    figure(app.UIFigure);

                catch
                    figure(app.UIFigure)
                    app.CSVFileEditField.Value = '';
                    app.UploadedCSVCheckBox.Value = 0;
                    app.StartingblockDropDownLabel.Visible = "off";
                    app.StartingBlockDropDown.Visible = "off";
                    errordlg('Invalid CSV file. Please upload a file with the right format.');
                    drawnow;
                    disableButtons_initialState(app);
                end

                app.data.Settings.UploadedCSV = app.CSVFileEditField.Value;

            end
        end

        % Button pushed function: SelectAudioDirButton
        function SelectAudioDirButtonPushed(app, event)
            audiopath = uigetdir;

            if audiopath==0 % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                app.CurrentAudioDir.Value = audiopath;
                drawnow;
                figure(app.UIFigure);
                app.ApplySettingsButton.Enable = "on";
                app.SavePresetButton.Enable = "on";
            end

        end

        % Value changed function: PlayCalibrationFileButton
        function PlayCalibrationFileButtonPushed(app, event)

            % get settings from playback method and mode
            readSettingsFromInterface(app);
            initializePlaybackObjects(app);

            if app.PlayCalibrationFileButton.Value == 0
                
                % if already pressed, stop sound
                releaseAudioObjs(app);

            else % if pressed, play tone

                % Read calibration signal 
                % TODO Change this to select speaker-specific SSN
                calibrationToneFullFile = fullfile(app.CurrentAudioDir.Value,'Noise','SSN_F1.wav');
                calibrationInfo = audioinfo(calibrationToneFullFile);

                % Read in random segment (and only 1 minute)
                maxCalTime = 5*60; % seconds
                lengthCalSignal = maxCalTime*app.data.Settings.Fs;
                randSample = round((calibrationInfo.TotalSamples-lengthCalSignal)*rand);

                calibrationSignal = audioread(calibrationToneFullFile,...
                    [randSample, randSample+lengthCalSignal-1]);

                % Apply equalization filter, if relevant
                if ~isempty(app.data.Settings.EqualizationFilter)
                    calibrationSignal = repmat(calibrationSignal,1,size(app.data.Settings.EqualizationFilter,2));

                    for cc = 1:size(app.data.Settings.EqualizationFilter,2)
                        calibrationSignal(:,cc) = filter(app.data.Settings.EqualizationFilter(:,cc),1,calibrationSignal(:,cc));
                    end

                end

                % Play the calibration file
                playSignal(app,calibrationSignal);

            end

        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)

            % Update user interface
            app.NextButton.Visible = "off";
            app.NextButton.Enable = "off";
            enableButtons_testState(app);

             % Erase data in progress plots
            cla(app.SentIdxVsSNR);
            cla(app.SNRVsPercent);

            % Update counters
            app.tmp.blockIdxInSession = app.tmp.blockIdxInSession + 1;
            app.tmp.sentIdxInBlock = 0;

            % Update file locations and names
            updateInfoForNextSent(app);

            % Load, mix, and play the sentence
            loadMixAndPlaySentence(app);

        end

        % Selection changed function: PlaybackMethodButtonGroup
        function PlaybackMethodChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
            % Handle radio buttons
            if app.BasicButton.Value == 1
                app.DriverDropDown.Enable = "off";
                app.DriverDropDownLabel.Enable = "off";
                app.ChannelsOutEditField.Enable = "off";
                app.ChannelsOutEditFieldLabel.Enable = "off";
                app.ChannelsInEditField.Enable = "off";
                app.ChannelsInEditFieldLabel.Enable = "off";
            elseif app.AudioToolboxButton.Value == 1
                app.DriverDropDown.Enable = "on";
                app.DriverDropDownLabel.Enable = "on";
                app.ChannelsOutEditField.Enable = "on";
                app.ChannelsOutEditFieldLabel.Enable = "on";
                app.ChannelsInEditField.Enable = "on";
                app.ChannelsInEditFieldLabel.Enable = "on";
            else
                app.DriverDropDown.Enable = "off";
                app.DriverDropDownLabel.Enable = "off";
                app.ChannelsOutEditField.Enable = "on";
                app.ChannelsOutEditFieldLabel.Enable = "on";
                app.ChannelsInEditField.Enable = "on";
                app.ChannelsInEditFieldLabel.Enable = "on";
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Do you want to close the window?', 'Confirm');
            if strcmpi(selection,'OK')

                % release audio objects
                releaseAudioObjs(app);

                % Save results for list/end of test
                saveFinalResultsInFile(app);

                % Delete the app
                delete(app);
            end
        end

        % Button pushed function: SelectEqualizationFilterButton
        function SelectEqualizationFilterButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.mat');
            if filename==0 % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                app.EqualizationFilterEditField.Value = '';
                return;
            else
                fullfilename = fullfile(pathname,filename);
                load(fullfilename); 
                app.data.Settings.EqualizationFilter = equalizationFilter;
                app.data.Settings.EqualizationFilterPath = fullfilename;
                app.EqualizationFilterEditField.Value = fullfilename;
                drawnow;
                figure(app.UIFigure);
                app.ApplySettingsButton.Enable = "on";
                app.SavePresetButton.Enable = "on";
            end
        end

        % Button pushed function: SavePresetButton
        function SavePresetButtonPushed(app, event)

            % Get user input for filename - workspace
            [file,path] = uiputfile(fullfile(app.tmp.presetDir,'*.mat'),'Save settings as a preset');

            app.UploadedPresetEditField.Value = '';

            if file==0 % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                return;
            else
                readSettingsFromInterface(app);
                setup = app.data.Settings;
                save(fullfile(path, file), 'setup', '-mat');
                drawnow;
                figure(app.UIFigure);
                app.SavePresetButton.Enable = "off";
            end

        end

        % Button pushed function: LoadPresetButton
        function LoadPresetButtonPushed(app, event)

            [filename,pathname] = uigetfile(fullfile(app.tmp.presetDir,'*.mat'));
            if filename==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                app.UploadedPresetEditField.Value = '';
                return
            else
                % loading variable
                fullfilename = fullfile(pathname,filename);
                load(fullfilename);
                app.data.Settings = setup;

                [~,f,~]=fileparts(fullfilename);
                filename=fullfile(f);

                app.UploadedPresetEditField.Value = filename;
                drawnow;
                figure(app.UIFigure);

                % Updating workspace settings with current
                writeSettingsToInterface(app);

            end

        end

        % Selection changed function: MaskerButtonGroup
        function MaskerButtonGroupSelectionChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
            if app.IntermittentButton.Value == 1
                app.DurationbeforeEditField.Enable = "on";
                app.DurationbeforeEditFieldLabel.Enable = "on";
                app.SecLabel.Enable = "on";
            else
                app.DurationbeforeEditField.Enable = "off";
                app.DurationbeforeEditFieldLabel.Enable = "off";
                app.SecLabel.Enable = "off";
            end
        end

        % Button pushed function: ApplySettingsButton
        function ApplySettingsButtonPushed(app, event)
            readSettingsFromInterface(app);
            app.ApplySettingsButton.Enable = "off";
        end

        % Selection changed function: ResultTypeButtonGroup
        function ResultTypeButtonGroupSelectionChanged(app, event)
            
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";

             % Handle radio buttons
            if app.PercentageCorrectButton.Value == 1
                app.AdaptiveMethodDropdownLabel.Enable = "off";
                app.AdaptiveMethodDropdown.Enable = "off";
                app.StoppingCriteriaLabel.Enable = "off";
                app.StoppingCriteriaEditField.Enable = "off";
                app.StoppingCriteriaDecibelLabel.Enable = "off";
                
            else
                app.AdaptiveMethodDropdownLabel.Enable = "on";
                app.AdaptiveMethodDropdown.Enable = "on";
                app.StoppingCriteriaLabel.Enable = "on";
                app.StoppingCriteriaEditField.Enable = "on";
                app.StoppingCriteriaDecibelLabel.Enable = "on";
            end

        end

        % Selection changed function: ScoringTypeButtonGroup
        function ScoringTypeButtonGroupSelectionChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";

        end

        % Selection changed function: PlaybackmodeButtonGroup
        function PlaybackModeButtonGroupSelectionChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";

        end

        % Selection changed function: PresentationModeButtonGroup
        function PresentationModeButtonGroupSelectionChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
            if app.AudioPresentationButton.Value == 1
                app.CurrentVideoDir.Enable = "off";
                app.PathtofilesLabel_2.Enable = "off";
                app.SelectVideoDirButton.Enable = "off";
            else
                app.CurrentVideoDir.Enable = "on";
                app.PathtofilesLabel_2.Enable = "on";
                app.SelectVideoDirButton.Enable = "on";
            end
        end

        % Selection changed function: CalibrationButtonGroup
        function CalibrationButtonGroupSelectionChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";

        end

        % Selection change function: AudioSettingsTabs
        function AudioSettingsTabsSelectionChanged(app, event)
            app.SavePresetButton.Enable = "on";
        end

        % Value changed function: TargetDropDown
        function TargetDropDownValueChanged(app, event)
            app.SavePresetButton.Enable = "on";
        end

        % Value changed function: MaskerDropDown
        function MaskerDropDownValueChanged(app, event)
            app.SavePresetButton.Enable = "on";

        end

        % Value changed function: ListNumberDropDown
        function ListNumberDropDownValueChanged(app, event)
            app.SavePresetButton.Enable = "on";

        end

        % Value changed function: ApplyHearingLossCompensationCheckbox
        function ApplyHearingLossCompensationCheckboxValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
            if app.ApplyHearingLossCompensationCheckbox.Value == 1
                app.AudiogramFileEditField.Enable = "on";
                app.AudiogramFileLabel.Enable = "on";
                app.AudiogramFileSelectButton.Enable = "on";
            else
                app.AudiogramFileEditField.Enable = "off";
                app.AudiogramFileLabel.Enable = "off";
                app.AudiogramFileSelectButton.Enable = "off";
            end

        end

        % Value changed function: TargetLevelEditField
        function TargetLevelEditFieldValueChanged(app, event)
            app.SavePresetButton.Enable = "on";
            val = double(app.TargetLevelEditField.Value - app.MaskerLevelEditField.Value);
            app.SNRValue.Text = sprintf('%1.1f', val);
        end

        % Value changed function: MaskerLevelEditField
        function MaskerLevelEditFieldValueChanged(app, event)
            app.SavePresetButton.Enable = "on";
            val = double(app.TargetLevelEditField.Value - app.MaskerLevelEditField.Value);
            app.SNRValue.Text = sprintf('%1.1f', val);
        end

        % Value changed function: DurationbeforeEditField
        function DurationbeforeEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end

        % Value changed function: LeftMeasuredEditField
        function LeftMeasuredEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end

        % Value changed function: RightMeasuredEditField
        function RightMeasuredEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end

        % Value changed function: EqualizationFilterEditField
        function EqualizationFilterEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end

        % Value changed function: CurrentAudioDir
        function CurrentAudioDirValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end

        % Value changed function: ChannelsOutEditField
        function ChannelsOutEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end

        % Value changed function: ChannelsInEditField
        function ChannelsInEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end

        % Value changed function: CurrentVideoDir
        function CurrentVideoDirValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end

        % Button pushed function: SelectVideoDirButton
        function SelectVideoDirButtonPushed(app, event)
            videopath = uigetdir;
            if videopath==0
                % user pressed cancel
                drawnow;
                figure(app.UIFigure)
                return
            else
                app.CurrentVideoDir.Value = videopath;
                drawnow;
                figure(app.UIFigure);
                app.ApplySettingsButton.Enable = "on";
                app.SavePresetButton.Enable = "on";
            end
        end

        % Value changed function: ListTypeDropDown
        function ListTypeDropDownValueChanged(app, event)

            app.ListNumberDropDown.Items = cellstr(num2str(unique(app.tmp.(app.ListTypeDropDown.Value).List, 'stable')));

        end

        % Value changed function: StoppingCriteriaEditField
        function StoppingCriteriaEditFieldValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end

        % Value changed function: AdaptiveMethodDropdown
        function AdaptiveMethodDropdownValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if app.TabGroup.SelectedTab.Title == "Test" && app.ApplySettingsButton.Enable == "on"
                selection = uiconfirm(app.UIFigure,'Do you want to apply or revert your changes?','Settings','Options',{'Apply','Revert'});
                if strcmpi(selection,'Apply')
                    readSettingsFromInterface(app);
                else
                    writeSettingsToInterface(app);
                end
            else
                app.ApplySettingsButton.Enable = "off";
            end
        end

        % Value changed function: AdjustSentenceLevelRMSCheckbox
        function AdjustSentenceLevelRMSCheckboxValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end

        % Value changed function: RandomizeOrderWithinListsCheckbox
        function RandomizeOrderWithinListsCheckboxValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end

        % Button pushed function: AudiogramFileSelectButton
        function AudiogramFileSelectButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.csv');
            if filename==0 % user pressed cancel
                drawnow;
                figure(app.UIFigure);
                app.AudiogramFileEditField.Value = '';
                return;
            else
                opts = detectImportOptions(fullfile(pathname,filename));
                opts.Encoding = 'UTF-8';
                opts.VariableNamingRule = 'Preserve';
                app.data.Settings.Audiogram = readtable(fullfile(pathname,filename));
                app.data.Settings.AudiogramFilePath = fullfile(pathname,filename);
                app.AudiogramFileEditField.Value = fullfile(pathname,filename);
                drawnow;
                figure(app.UIFigure);
                app.ApplySettingsButton.Enable = "on";
                app.SavePresetButton.Enable = "on";
            end
        end

        % Value changed function: DriverDropDown
        function DriverDropDownValueChanged(app, event)
            app.ApplySettingsButton.Enable = "on";
            app.SavePresetButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BasicInformationPanel = uipanel(app.UIFigure);
            app.BasicInformationPanel.Title = 'Basic information';
            app.TesterIDEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.TesterIDEditFieldLabel.Text = 'Tester ID';
            app.TesterIDEditField = uieditfield(app.BasicInformationPanel, 'text');
            app.TesterIDEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.ParticipantIDEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.ParticipantIDEditFieldLabel.Text = 'Participant ID';
            app.ParticipantIDEditField = uieditfield(app.BasicInformationPanel, 'text');
            app.ParticipantIDEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditFieldLabel = uilabel(app.BasicInformationPanel);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.BasicInformationPanel, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @TestInfoChanged, true);
            app.FilenameEditField.Editable = 'off';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.TestTab = uitab(app.TabGroup);
            app.TestTab.Title = 'Test';
            app.ScoringPanel = uipanel(app.TestTab);
            app.ScoringPanel.Title = 'Scoring';
            app.ContinueButton = uibutton(app.ScoringPanel, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.MarkallButton = uibutton(app.ScoringPanel, 'state');
            app.MarkallButton.ValueChangedFcn = createCallbackFcn(app, @MarkallButtonValueChanged, true);
            app.MarkallButton.Text = 'Mark all';
            app.W1Button = uibutton(app.ScoringPanel, 'state');
            app.W1Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W1Button.Text = 'W1';
            app.W2Button = uibutton(app.ScoringPanel, 'state');
            app.W2Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W2Button.Text = 'W2';
            app.W3Button = uibutton(app.ScoringPanel, 'state');
            app.W3Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W3Button.Text = 'W3';
            app.W4Button = uibutton(app.ScoringPanel, 'state');
            app.W4Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W4Button.Text = 'W4';
            app.W5Button = uibutton(app.ScoringPanel, 'state');
            app.W5Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W5Button.Text = 'W5';
            app.W6Button = uibutton(app.ScoringPanel, 'state');
            app.W6Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W6Button.Text = 'W6';
            app.W7Button = uibutton(app.ScoringPanel, 'state');
            app.W7Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W7Button.Text = 'W7';
            app.W8Button = uibutton(app.ScoringPanel, 'state');
            app.W8Button.ValueChangedFcn = createCallbackFcn(app, @wordSelected, true);
            app.W8Button.Text = 'W8';
            app.W9Button = uibutton(app.ScoringPanel, 'state');
            app.W9Button.Text = 'W9';
            app.W10Button = uibutton(app.ScoringPanel, 'state');
            app.W10Button.Text = 'W10';
            app.W11Button = uibutton(app.ScoringPanel, 'state');
            app.W11Button.Text = 'W11';
            app.W12Button = uibutton(app.ScoringPanel, 'state');
            app.W12Button.Text = 'W12';
            app.currentSent = uilabel(app.ScoringPanel);
            app.currentSent.Tag = 'currentSent';
            app.currentSent.Text = 'currentSent';
            app.StartButton = uibutton(app.TestTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.TestTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DanskStningstestDASTLabel = uilabel(app.TestTab);
            app.DanskStningstestDASTLabel.Text = 'Dansk Sætningstest (DAST)';
            app.PauseButton = uibutton(app.TestTab, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @PauseButtonValueChanged, true);
            app.PauseButton.Text = 'Pause';
            app.NextButton = uibutton(app.TestTab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.ProgressPanel = uipanel(app.TestTab);
            app.ProgressPanel.Title = 'Progress';
            app.SNRVsPercent = uiaxes(app.ProgressPanel);
            xlabel(app.SNRVsPercent, 'SNR')
            ylabel(app.SNRVsPercent, '%')
            app.SNRVsPercent.Visible = 'off';
            app.SentIdxVsSNR = uiaxes(app.ProgressPanel);
            xlabel(app.SentIdxVsSNR, 'Sentence')
            ylabel(app.SentIdxVsSNR, 'SNR')
            app.SentIdxVsSNR.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20];
            app.SentIdxVsSNR.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'};
            app.SentIdxVsSNR.Visible = 'off';
            app.currentBlock = uilabel(app.ProgressPanel);
            app.currentBlock.Tag = 'currentBlock';
            app.currentBlock.Text = 'currentBlock';
            app.Instructions = uilabel(app.TestTab);
            app.Instructions.Visible = 'off';
            app.Instructions.Text = 'Mark correctly repeated words';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.SettingsPanelinSettingsTab = uipanel(app.SettingsTab);
            app.SettingsPanelinSettingsTab.Tag = 'Panel for more test settings';
            app.ResultTypeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.ResultTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResultTypeButtonGroupSelectionChanged, true);
            app.ResultTypeButtonGroup.Title = 'Result type';
            app.PercentageCorrectButton = uiradiobutton(app.ResultTypeButtonGroup);
            app.PercentageCorrectButton.Text = 'Percentage correct';
            app.SpeechReceptionThresholdButton = uiradiobutton(app.ResultTypeButtonGroup);
            app.SpeechReceptionThresholdButton.Text = 'Speech reception threshold';
            app.SpeechReceptionThresholdButton.Value = true;
            app.AdaptiveMethodDropdown = uidropdown(app.ResultTypeButtonGroup);
            app.AdaptiveMethodDropdown.Items = {'Default'};
            app.AdaptiveMethodDropdown.ValueChangedFcn = createCallbackFcn(app, @AdaptiveMethodDropdownValueChanged, true);
            app.AdaptiveMethodDropdown.Value = 'Default';
            app.AdaptiveMethodDropdownLabel = uilabel(app.ResultTypeButtonGroup);
            app.AdaptiveMethodDropdownLabel.Text = 'Method';
            app.PresentationModeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.PresentationModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PresentationModeButtonGroupSelectionChanged, true);
            app.PresentationModeButtonGroup.Title = 'Presentation mode';
            app.AudioPresentationButton = uiradiobutton(app.PresentationModeButtonGroup);
            app.AudioPresentationButton.Text = 'Audio';
            app.AudioPresentationButton.Value = true;
            app.AudioVisualPresentationButton = uiradiobutton(app.PresentationModeButtonGroup);
            app.AudioVisualPresentationButton.Text = 'Audio-visual';
            app.SelectAudioDirButton = uibutton(app.PresentationModeButtonGroup, 'push');
            app.SelectAudioDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAudioDirButtonPushed, true);
            app.SelectAudioDirButton.Text = 'Select';
            app.PathtofilesLabel_2 = uilabel(app.PresentationModeButtonGroup);
            app.PathtofilesLabel_2.Enable = 'off';
            app.PathtofilesLabel_2.Text = 'Video path';
            app.CurrentVideoDir = uieditfield(app.PresentationModeButtonGroup, 'text');
            app.CurrentVideoDir.ValueChangedFcn = createCallbackFcn(app, @CurrentVideoDirValueChanged, true);
            app.CurrentVideoDir.Editable = 'off';
            app.CurrentVideoDir.Enable = 'off';
            app.SelectVideoDirButton = uibutton(app.PresentationModeButtonGroup, 'push');
            app.SelectVideoDirButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoDirButtonPushed, true);
            app.SelectVideoDirButton.Enable = 'off';
            app.SelectVideoDirButton.Text = 'Select';
            app.PathtofilesLabel = uilabel(app.PresentationModeButtonGroup);
            app.PathtofilesLabel.Text = 'Audio path';
            app.CurrentAudioDir = uieditfield(app.PresentationModeButtonGroup, 'text');
            app.CurrentAudioDir.ValueChangedFcn = createCallbackFcn(app, @CurrentAudioDirValueChanged, true);
            app.CurrentAudioDir.Editable = 'off';
            app.ScoringTypeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.ScoringTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ScoringTypeButtonGroupSelectionChanged, true);
            app.ScoringTypeButtonGroup.Title = 'Scoring type';
            app.KeywordBasedButton = uiradiobutton(app.ScoringTypeButtonGroup);
            app.KeywordBasedButton.Text = 'Keyword-based';
            app.KeywordBasedButton.Value = true;
            app.WordByWordButton = uiradiobutton(app.ScoringTypeButtonGroup);
            app.WordByWordButton.Text = 'Word-by-word';
            app.PlaybackMethodButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.PlaybackMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlaybackMethodChanged, true);
            app.PlaybackMethodButtonGroup.Title = 'Playback method';
            app.ChannelsOutEditFieldLabel = uilabel(app.PlaybackMethodButtonGroup);
            app.ChannelsOutEditFieldLabel.Text = 'Channel(s) out';
            app.ChannelsOutEditField = uieditfield(app.PlaybackMethodButtonGroup, 'text');
            app.ChannelsOutEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelsOutEditFieldValueChanged, true);
            app.ChannelsOutEditField.Tooltip = {'Specify the channel numbers for the output. Single and multiple values are accepted.'};
            app.DriverDropDown = uidropdown(app.PlaybackMethodButtonGroup);
            app.DriverDropDown.Items = {'ASIO', 'DirectSound', 'WASAPI', 'ALSA', 'CoreAudio'};
            app.DriverDropDown.ValueChangedFcn = createCallbackFcn(app, @DriverDropDownValueChanged, true);
            app.DriverDropDown.Value = 'ASIO';
            app.DriverDropDownLabel = uilabel(app.PlaybackMethodButtonGroup);
            app.DriverDropDownLabel.Text = 'Driver';
            app.PlayrecButton = uiradiobutton(app.PlaybackMethodButtonGroup);
            app.PlayrecButton.Text = 'Playrec';
            app.AudioToolboxButton = uiradiobutton(app.PlaybackMethodButtonGroup);
            app.AudioToolboxButton.Text = 'Audio Toolbox';
            app.AudioToolboxButton.Value = true;
            app.BasicButton = uiradiobutton(app.PlaybackMethodButtonGroup);
            app.BasicButton.Text = 'Basic';
            app.ChannelsInEditFieldLabel = uilabel(app.PlaybackMethodButtonGroup);
            app.ChannelsInEditFieldLabel.Text = 'Channel(s) in';
            app.ChannelsInEditField = uieditfield(app.PlaybackMethodButtonGroup, 'text');
            app.ChannelsInEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelsInEditFieldValueChanged, true);
            app.ChannelsInEditField.Tooltip = {'Specify input devices, e.g. microphone channel.'};
            app.PlaybackmodeButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.PlaybackmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlaybackModeButtonGroupSelectionChanged, true);
            app.PlaybackmodeButtonGroup.Title = 'Playback mode';
            app.StereoButton = uiradiobutton(app.PlaybackmodeButtonGroup);
            app.StereoButton.Text = 'Stereo';
            app.StereoButton.Value = true;
            app.MonoButton = uiradiobutton(app.PlaybackmodeButtonGroup);
            app.MonoButton.Enable = 'off';
            app.MonoButton.Text = 'Mono';
            app.MultiButton = uiradiobutton(app.PlaybackmodeButtonGroup);
            app.MultiButton.Enable = 'off';
            app.MultiButton.Text = 'Multi';
            app.CalibrationButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.CalibrationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalibrationButtonGroupSelectionChanged, true);
            app.CalibrationButtonGroup.Title = 'Calibration';
            app.dBZLabel = uilabel(app.CalibrationButtonGroup);
            app.dBZLabel.Text = 'dB(Z)';
            app.MeasuredLabel = uilabel(app.CalibrationButtonGroup);
            app.MeasuredLabel.Text = 'Measured';
            app.PlayCalibrationFileButton = uibutton(app.CalibrationButtonGroup, 'state');
            app.PlayCalibrationFileButton.ValueChangedFcn = createCallbackFcn(app, @PlayCalibrationFileButtonPushed, true);
            app.PlayCalibrationFileButton.Text = 'Play';
            app.EqualizationfilterEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.EqualizationfilterEditFieldLabel.Text = 'Equalization filter';
            app.EqualizationFilterEditField = uieditfield(app.CalibrationButtonGroup, 'text');
            app.EqualizationFilterEditField.ValueChangedFcn = createCallbackFcn(app, @EqualizationFilterEditFieldValueChanged, true);
            app.EqualizationFilterEditField.Editable = 'off';
            app.SelectEqualizationFilterButton = uibutton(app.CalibrationButtonGroup, 'push');
            app.SelectEqualizationFilterButton.ButtonPushedFcn = createCallbackFcn(app, @SelectEqualizationFilterButtonPushed, true);
            app.SelectEqualizationFilterButton.Text = 'Select';
            app.RightMeasuredEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.RightMeasuredEditFieldLabel.Text = 'R';
            app.RightMeasuredEditField = uieditfield(app.CalibrationButtonGroup, 'numeric');
            app.RightMeasuredEditField.ValueChangedFcn = createCallbackFcn(app, @RightMeasuredEditFieldValueChanged, true);
            app.RightMeasuredEditField.Value = 95;
            app.LeftMeasuredEditField = uieditfield(app.CalibrationButtonGroup, 'numeric');
            app.LeftMeasuredEditField.ValueChangedFcn = createCallbackFcn(app, @LeftMeasuredEditFieldValueChanged, true);
            app.LeftMeasuredEditField.Value = 95;
            app.LeftMeasuredEditFieldLabel = uilabel(app.CalibrationButtonGroup);
            app.LeftMeasuredEditFieldLabel.Text = 'L';
            app.EqualizationfilterEditFieldLabel_2 = uilabel(app.CalibrationButtonGroup);
            app.EqualizationfilterEditFieldLabel_2.Text = 'Calibration';
            app.SavePresetButton = uibutton(app.SettingsPanelinSettingsTab, 'push');
            app.SavePresetButton.ButtonPushedFcn = createCallbackFcn(app, @SavePresetButtonPushed, true);
            app.SavePresetButton.Text = 'Save preset';
            app.ApplySettingsButton = uibutton(app.SettingsPanelinSettingsTab, 'push');
            app.ApplySettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySettingsButtonPushed, true);
            app.ApplySettingsButton.Text = 'Apply settings';
            app.MiscellaneousButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.MiscellaneousButtonGroup.Title = 'Miscellaneous';
            app.ApplyHearingLossCompensationCheckbox = uicheckbox(app.MiscellaneousButtonGroup);
            app.ApplyHearingLossCompensationCheckbox.ValueChangedFcn = createCallbackFcn(app, @ApplyHearingLossCompensationCheckboxValueChanged, true);
            app.ApplyHearingLossCompensationCheckbox.Text = 'Apply hearing loss compensation';
            app.RandomizeOrderWithinListsCheckbox = uicheckbox(app.MiscellaneousButtonGroup);
            app.RandomizeOrderWithinListsCheckbox.ValueChangedFcn = createCallbackFcn(app, @RandomizeOrderWithinListsCheckboxValueChanged, true);
            app.RandomizeOrderWithinListsCheckbox.Text = 'Randomize order within lists';
            app.AdjustSentenceLevelRMSCheckbox = uicheckbox(app.MiscellaneousButtonGroup);
            app.AdjustSentenceLevelRMSCheckbox.ValueChangedFcn = createCallbackFcn(app, @AdjustSentenceLevelRMSCheckboxValueChanged, true);
            app.AdjustSentenceLevelRMSCheckbox.Text = 'Adjust sentence-level RMS';
            app.AdjustSentenceLevelRMSCheckbox.Value = true;
            app.AudiogramFileLabel = uilabel(app.MiscellaneousButtonGroup);
            app.AudiogramFileLabel.Enable = 'off';
            app.AudiogramFileLabel.Text = 'Audiograms';
            app.AudiogramFileSelectButton = uibutton(app.MiscellaneousButtonGroup, 'push');
            app.AudiogramFileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @AudiogramFileSelectButtonPushed, true);
            app.AudiogramFileSelectButton.Enable = 'off';
            app.AudiogramFileSelectButton.Text = 'Select';
            app.AudiogramFileEditField = uieditfield(app.MiscellaneousButtonGroup, 'text');
            app.AudiogramFileEditField.Editable = 'off';
            app.AudiogramFileEditField.Enable = 'off';
            app.MaskerButtonGroup = uibuttongroup(app.SettingsPanelinSettingsTab);
            app.MaskerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MaskerButtonGroupSelectionChanged, true);
            app.MaskerButtonGroup.Title = 'Masker';
            app.ContinuousButton = uiradiobutton(app.MaskerButtonGroup);
            app.ContinuousButton.Text = 'Continuous';
            app.IntermittentButton = uiradiobutton(app.MaskerButtonGroup);
            app.IntermittentButton.Text = 'Intermittent';
            app.IntermittentButton.Value = true;
            app.SecLabel = uilabel(app.MaskerButtonGroup);
            app.SecLabel.Text = 'sec';
            app.DurationbeforeEditFieldLabel = uilabel(app.MaskerButtonGroup);
            app.DurationbeforeEditFieldLabel.Text = 'Duration before';
            app.DurationbeforeEditField = uieditfield(app.MaskerButtonGroup, 'numeric');
            app.DurationbeforeEditField.Limits = [0 Inf];
            app.DurationbeforeEditField.ValueChangedFcn = createCallbackFcn(app, @DurationbeforeEditFieldValueChanged, true);
            app.DurationbeforeEditField.Value = 0.5;
            app.StoppingCriteriaDecibelLabel = uilabel(app.SettingsPanelinSettingsTab);
            app.StoppingCriteriaDecibelLabel.Text = 'dB';
            app.StoppingCriteriaLabel = uilabel(app.SettingsPanelinSettingsTab);
            app.StoppingCriteriaLabel.Text = 'Stop if SE reaches';
            app.StoppingCriteriaEditField = uieditfield(app.SettingsPanelinSettingsTab, 'numeric');
            app.StoppingCriteriaEditField.Limits = [0 Inf];
            app.StoppingCriteriaEditField.ValueChangedFcn = createCallbackFcn(app, @StoppingCriteriaEditFieldValueChanged, true);
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Tag = 'SettingsPanel';
            app.AudioSettingsTabs = uitabgroup(app.Panel);
            app.AudioSettingsTabs.SelectionChangedFcn = createCallbackFcn(app, @AudioSettingsTabsSelectionChanged, true);
            app.QuickstartTab = uitab(app.AudioSettingsTabs);
            app.QuickstartTab.Title = 'Quick start';
            app.dBSPLLabel = uilabel(app.QuickstartTab);
            app.dBSPLLabel.Text = 'dB SPL';
            app.dBSPLLabel_2 = uilabel(app.QuickstartTab);
            app.dBSPLLabel_2.Text = 'dB SPL';
            app.TargetDropDownLabel = uilabel(app.QuickstartTab);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.QuickstartTab);
            app.TargetDropDown.Items = {'F1', 'F2', 'M1', 'M2', 'Random talker', 'Mixed talkers'};
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetDropDownValueChanged, true);
            app.TargetDropDown.Value = 'F1';
            app.ListnumberDropDownLabel = uilabel(app.QuickstartTab);
            app.ListnumberDropDownLabel.Text = 'List number';
            app.ListNumberDropDown = uidropdown(app.QuickstartTab);
            app.ListNumberDropDown.Items = {'1'};
            app.ListNumberDropDown.ValueChangedFcn = createCallbackFcn(app, @ListNumberDropDownValueChanged, true);
            app.ListNumberDropDown.Value = '1';
            app.MaskerLabel = uilabel(app.QuickstartTab);
            app.MaskerLabel.Text = 'Masker';
            app.MaskerDropDown = uidropdown(app.QuickstartTab);
            app.MaskerDropDown.Items = {'SSN_F1'};
            app.MaskerDropDown.ValueChangedFcn = createCallbackFcn(app, @MaskerDropDownValueChanged, true);
            app.MaskerDropDown.Value = 'SSN_F1';
            app.TargetlevelLabel = uilabel(app.QuickstartTab);
            app.TargetlevelLabel.Text = 'Target level';
            app.TargetLevelEditField = uieditfield(app.QuickstartTab, 'numeric');
            app.TargetLevelEditField.Limits = [0 Inf];
            app.TargetLevelEditField.ValueChangedFcn = createCallbackFcn(app, @TargetLevelEditFieldValueChanged, true);
            app.TargetLevelEditField.Value = 70;
            app.MaskerlevelEditFieldLabel = uilabel(app.QuickstartTab);
            app.MaskerlevelEditFieldLabel.Text = 'Masker level';
            app.MaskerLevelEditField = uieditfield(app.QuickstartTab, 'numeric');
            app.MaskerLevelEditField.Limits = [0 Inf];
            app.MaskerLevelEditField.ValueChangedFcn = createCallbackFcn(app, @MaskerLevelEditFieldValueChanged, true);
            app.MaskerLevelEditField.Value = 70;
            app.SNRLabel = uilabel(app.QuickstartTab);
            app.SNRLabel.Text = 'SNR';
            app.SNRValue = uilabel(app.QuickstartTab);
            app.SNRValue.Text = '0.0';
            app.dBLabel = uilabel(app.QuickstartTab);
            app.dBLabel.Text = 'dB';
            app.ListtypeDropDownLabel = uilabel(app.QuickstartTab);
            app.ListtypeDropDownLabel.Text = 'List type';
            app.ListTypeDropDown = uidropdown(app.QuickstartTab);
            app.ListTypeDropDown.Items = {'A_F1'};
            app.ListTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ListTypeDropDownValueChanged, true);
            app.ListTypeDropDown.Value = 'A_F1';
            app.AdvancedTab = uitab(app.AudioSettingsTabs);
            app.AdvancedTab.Title = 'Advanced';
            app.UploadButton = uibutton(app.AdvancedTab, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.CSVfileEditFieldLabel = uilabel(app.AdvancedTab);
            app.CSVfileEditFieldLabel.Text = 'CSV file';
            app.CSVFileEditField = uieditfield(app.AdvancedTab, 'text');
            app.CSVFileEditField.Editable = 'off';
            app.UploadedCSVCheckBox = uicheckbox(app.AdvancedTab);
            app.UploadedCSVCheckBox.Enable = 'off';
            app.UploadedCSVCheckBox.Text = 'CheckUploadedCSV';
            app.StartingblockDropDownLabel = uilabel(app.AdvancedTab);
            app.StartingblockDropDownLabel.Text = 'Starting block';
            app.StartingBlockDropDown = uidropdown(app.AdvancedTab);
            app.StartingBlockDropDown.Items = {};
            app.StartingBlockDropDown.Value = {};
            app.LoadPresetButton = uibutton(app.Panel, 'push');
            app.LoadPresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPresetButtonPushed, true);
            app.LoadPresetButton.Text = 'Load preset';
            app.UploadedPresetEditField = uieditfield(app.Panel, 'text');
            app.UploadedPresetEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DAST

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end